classdef PlatToroidalProp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlatToroidalPropv10UIFigure     matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        OffsetsMethodTab                matlab.ui.container.Tab
        Mainparameterinformation        matlab.ui.container.Panel
        Export                          matlab.ui.control.Button
        ShowinPoints                    matlab.ui.control.Button
        ShowinSurface                   matlab.ui.control.Button
        ShowinSection                   matlab.ui.control.Button
        Numberofbladesz                 matlab.ui.control.NumericEditField
        NumberofbladeszLabel            matlab.ui.control.Label
        HubdiameterratioRhRE            matlab.ui.control.NumericEditField
        HubdiameterratioRhRLabel        matlab.ui.control.Label
        DiameterD                       matlab.ui.control.NumericEditField
        DiameterDLabel                  matlab.ui.control.Label
        OverallwheelbaseL               matlab.ui.control.NumericEditField
        OverallwheelbaseLLabel          matlab.ui.control.Label
        Radialinfo                      matlab.ui.control.Table
        Radialparameterinformation      matlab.ui.container.Panel
        ImportRadial                    matlab.ui.control.Button
        SectionInfo                     matlab.ui.control.Table
        Bladesectiondefination          matlab.ui.container.Panel
        ImportSection                   matlab.ui.control.Button
        ParametricMethodTab             matlab.ui.container.Tab
        Mainparameterinformation_2      matlab.ui.container.Panel
        Export_2                        matlab.ui.control.Button
        ShowinPoints_2                  matlab.ui.control.Button
        ShowinSurface_2                 matlab.ui.control.Button
        ShowinSection_2                 matlab.ui.control.Button
        Numberofbladesz_2               matlab.ui.control.NumericEditField
        NumberofbladeszLabel_2          matlab.ui.control.Label
        HubdiameterratioRhRE_2          matlab.ui.control.NumericEditField
        HubdiameterratioRhRLabel_2      matlab.ui.control.Label
        DiameterD_2                     matlab.ui.control.NumericEditField
        DiameterDLabel_2                matlab.ui.control.Label
        OverallwheelbaseL_2             matlab.ui.control.NumericEditField
        OverallwheelbaseLLabel_2        matlab.ui.control.Label
        Radialparameterinformation_2    matlab.ui.container.Panel
        UEditField                      matlab.ui.control.NumericEditField
        UEditFieldLabel                 matlab.ui.control.Label
        VEditField                      matlab.ui.control.NumericEditField
        VEditFieldLabel                 matlab.ui.control.Label
        yEditField_10                   matlab.ui.control.NumericEditField
        yEditField_10Label              matlab.ui.control.Label
        xEditField_10                   matlab.ui.control.NumericEditField
        xEditField_10Label              matlab.ui.control.Label
        UITable_4                       matlab.ui.control.Table
        UITable_3                       matlab.ui.control.Table
        ShowHydrofoil_9                 matlab.ui.control.Button
        ShowHydrofoil_8                 matlab.ui.control.Button
        DefaultControlPointsLabel_2     matlab.ui.control.Label
        ShowHydrofoil_7                 matlab.ui.control.Button
        ShowHydrofoil_6                 matlab.ui.control.Button
        AddControlpointCheckBox_2       matlab.ui.control.CheckBox
        Panel_3                         matlab.ui.container.Panel
        yEditField_18                   matlab.ui.control.NumericEditField
        yEditField_18Label              matlab.ui.control.Label
        xEditField_18                   matlab.ui.control.NumericEditField
        xEditField_18Label              matlab.ui.control.Label
        yEditField_17                   matlab.ui.control.NumericEditField
        yEditField_17Label              matlab.ui.control.Label
        xEditField_17                   matlab.ui.control.NumericEditField
        xEditField_17Label              matlab.ui.control.Label
        yEditField_16                   matlab.ui.control.NumericEditField
        yEditField_16Label              matlab.ui.control.Label
        xEditField_16                   matlab.ui.control.NumericEditField
        xEditField_16Label              matlab.ui.control.Label
        yEditField_15                   matlab.ui.control.NumericEditField
        yEditField_15Label              matlab.ui.control.Label
        xEditField_15                   matlab.ui.control.NumericEditField
        xEditField_15Label              matlab.ui.control.Label
        yEditField_14                   matlab.ui.control.NumericEditField
        yEditField_14Label              matlab.ui.control.Label
        xEditField_14                   matlab.ui.control.NumericEditField
        xEditField_14Label              matlab.ui.control.Label
        yEditField_13                   matlab.ui.control.NumericEditField
        yEditField_13Label              matlab.ui.control.Label
        xEditField_13                   matlab.ui.control.NumericEditField
        xEditField_13Label              matlab.ui.control.Label
        yEditField_12                   matlab.ui.control.NumericEditField
        yEditField_12Label              matlab.ui.control.Label
        xEditField_12                   matlab.ui.control.NumericEditField
        xEditField_12Label              matlab.ui.control.Label
        yEditField_11                   matlab.ui.control.NumericEditField
        yEditField_11Label              matlab.ui.control.Label
        xEditField_11                   matlab.ui.control.NumericEditField
        xEditField_11Label              matlab.ui.control.Label
        ParametersDropDown              matlab.ui.control.DropDown
        ParametersDropDownLabel         matlab.ui.control.Label
        Bladesectiondefination_2        matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        CSTmethodTab                    matlab.ui.container.Tab
        ShowHydrofoil                   matlab.ui.control.Button
        NumberofpointsEditField         matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel    matlab.ui.control.Label
        trailingedgethicknessEditField  matlab.ui.control.NumericEditField
        trailingedgethicknessEditFieldLabel  matlab.ui.control.Label
        weightofupperEditField          matlab.ui.control.NumericEditField
        weightofupperEditFieldLabel     matlab.ui.control.Label
        weightoflowerEditField          matlab.ui.control.NumericEditField
        weightoflowerEditFieldLabel     matlab.ui.control.Label
        BsplinemethodTab                matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        UITable_2                       matlab.ui.control.Table
        ShowHydrofoil_5                 matlab.ui.control.Button
        ShowHydrofoil_4                 matlab.ui.control.Button
        DefaultControlPointsLabel       matlab.ui.control.Label
        ShowHydrofoil_3                 matlab.ui.control.Button
        ShowHydrofoil_2                 matlab.ui.control.Button
        yEditField_9                    matlab.ui.control.NumericEditField
        yEditField_9Label               matlab.ui.control.Label
        xEditField_9                    matlab.ui.control.NumericEditField
        xEditField_9Label               matlab.ui.control.Label
        AddControlpointCheckBox         matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        yEditField_8                    matlab.ui.control.NumericEditField
        yEditField_8Label               matlab.ui.control.Label
        xEditField_8                    matlab.ui.control.NumericEditField
        xEditField_8Label               matlab.ui.control.Label
        yEditField_7                    matlab.ui.control.NumericEditField
        yEditField_7Label               matlab.ui.control.Label
        xEditField_7                    matlab.ui.control.NumericEditField
        xEditField_7Label               matlab.ui.control.Label
        yEditField_6                    matlab.ui.control.NumericEditField
        yEditField_6Label               matlab.ui.control.Label
        xEditField_6                    matlab.ui.control.NumericEditField
        xEditField_6Label               matlab.ui.control.Label
        yEditField_5                    matlab.ui.control.NumericEditField
        yEditField_5Label               matlab.ui.control.Label
        xEditField_5                    matlab.ui.control.NumericEditField
        xEditField_5Label               matlab.ui.control.Label
        yEditField_4                    matlab.ui.control.NumericEditField
        yEditField_4Label               matlab.ui.control.Label
        xEditField_4                    matlab.ui.control.NumericEditField
        xEditField_4Label               matlab.ui.control.Label
        yEditField_3                    matlab.ui.control.NumericEditField
        yEditField_3Label               matlab.ui.control.Label
        xEditField_3                    matlab.ui.control.NumericEditField
        xEditField_3Label               matlab.ui.control.Label
        yEditField_2                    matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        xEditField_2                    matlab.ui.control.NumericEditField
        xEditField_2Label               matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        UIAxesRake                      matlab.ui.control.UIAxes
        UIAxesPD                        matlab.ui.control.UIAxes
        UIAxesLateral                   matlab.ui.control.UIAxes
        UIAxesRoll                      matlab.ui.control.UIAxes
        UIAxesVertical                  matlab.ui.control.UIAxes
        UIAxesangleSkew                 matlab.ui.control.UIAxes
        UIAxesfmaxC                     matlab.ui.control.UIAxes
        UIAxestmaxC                     matlab.ui.control.UIAxes
        UIAxescD                        matlab.ui.control.UIAxes
        UIAxesrR                        matlab.ui.control.UIAxes
        UIAxesYZ                        matlab.ui.control.UIAxes
        UIAxesXY                        matlab.ui.control.UIAxes
        UIAxesPre                       matlab.ui.control.UIAxes
        UIAxesl                         matlab.ui.control.UIAxes
        FigSectionInfo                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %------------------------------- Copyright --------------------------------
            % Copyright (c) 2025 Wuhan University of Technology.
            % You are free to use the PlatToroidalProp for research purposes.
            %--------------------------------------------------------------------------
            cd(fileparts(mfilename('fullpath')));
            addpath(genpath(cd));
        end

        % Button pushed function: ImportSection
        function ImportSectionButtonPushed(app, event)
            % Open a file selection dialog
            [file, path] = uigetfile({'*.xlsx;*.csv;*.txt', 'Data Files (*.xlsx, *.csv, *.txt)'}, 'Select a Data File');
            % Check if the user canceled the selection
            if isequal(file, 0)
                return; % If canceled, simply return
            end

            % Full path to the selected file
            filename = fullfile(path, file);

            % Get the file extension
            [~, ~, ext] = fileparts(filename);

            % Read the file based on its extension
            switch lower(ext)
                case {'.xlsx', '.xls'}
                    data = readtable(filename, 'ReadVariableNames', true); % Read Excel file
                case '.csv'
                    data = readtable(filename, 'ReadVariableNames', true); % Read CSV file
                case '.txt'
                    data = readtable(filename, 'Delimiter', {'\t', ','}, 'ReadVariableNames', true); % Read TXT file
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
                    return;
            end

            % Display the data in the UITable
            app.SectionInfo.Data=data;

            % Update the column names
            app.SectionInfo.ColumnName = data.Properties.VariableNames;
            cla(app.FigSectionInfo);
            % Updata the FigSectionInfo
            hold(app.FigSectionInfo,"on")
            plot(app.FigSectionInfo, data{:,1}, data{:,2},LineStyle="-",LineWidth=1.5,Color="r");
            plot(app.FigSectionInfo, data{:,1}, data{:,3},LineStyle="--",LineWidth=1.5,Color="b");
            legend(app.FigSectionInfo,'t/tmax','f/fmax');


        end

        % Button pushed function: ImportRadial
        function ImportRadialButtonPushed(app, event)
            % Open a file selection dialog
            [file, path] = uigetfile({'*.xlsx;*.csv;*.txt', 'Data Files (*.xlsx, *.csv, *.txt)'}, 'Select a Data File');
            % Check if the user canceled the selection
            if isequal(file, 0)
                return; % If canceled, simply return
            end

            % Full path to the selected file
            filename = fullfile(path, file);

            % Get the file extension
            [~, ~, ext] = fileparts(filename);

            % Read the file based on its extension
            switch lower(ext)
                case {'.xlsx', '.xls'}
                    data = readtable(filename, 'ReadVariableNames', true); % Read Excel file
                case '.csv'
                    data = readtable(filename, 'ReadVariableNames', true); % Read CSV file
                case '.txt'
                    data = readtable(filename, 'Delimiter', {'\t', ','}, 'ReadVariableNames', true); % Read TXT file
                otherwise
                    uialert(app.UIFigure, 'Unsupported file format.', 'Error');
                    return;
            end

            % Display the data in the UITable
            app.Radialinfo.Data=data;

            % Update the column names
            app.Radialinfo.ColumnName = data.Properties.VariableNames;

            % Updata the FigSectionInfo
            cla(app.UIAxesl);
            plot(app.UIAxesl, data{:,1}, data{:,2},LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxesrR);
            plot(app.UIAxesrR, data{:,2}, data{:,2},LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxescD);
            plot(app.UIAxescD, data{:,3}, data{:,2},LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxesPD);
            plot(app.UIAxesPD, data{:,4}, data{:,2},LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxestmaxC);
            plot(app.UIAxestmaxC, data{:,5}, data{:,2},LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxesfmaxC);
            plot(app.UIAxesfmaxC, data{:,6}, data{:,2},LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxesangleSkew);
            plot(app.UIAxesangleSkew, data{:,7}, data{:,2},LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxesRake);
            plot(app.UIAxesRake, data{:,8}, data{:,2},LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxesLateral);
            plot(app.UIAxesLateral, data{:,9}, data{:,2},LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxesRoll);
            plot(app.UIAxesRoll, data{:,10}, data{:,2},LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxesVertical);
            plot(app.UIAxesVertical, data{:,11}, data{:,2},LineStyle="-",LineWidth=1.5,Color="r");

        end

        % Button pushed function: ShowinPoints
        function ShowinPointsButtonPushed(app, event)
            try
                datasection=app.SectionInfo.Data;
                xofsection=datasection{:,1}';
                tdivtmax=datasection{:,2}';
                fdivfmax=datasection{:,3}';
                dataRadial=app.Radialinfo.Data;
                l=dataRadial{:,1}';
                rdivR=dataRadial{:,2}';
                bdivD=dataRadial{:,3}';
                PdivD=dataRadial{:,4}';
                tdivb=dataRadial{:,5}';
                fdivb=dataRadial{:,6}';
                thets=dataRadial{:,7}';
                xl=dataRadial{:,8}';
                psi=dataRadial{:,9}';
                gamma=dataRadial{:,10}';
                alpha=dataRadial{:,11}';
                [xf,yf,zf,xb,yb,zb] = GetPropPoint_offsets(app.DiameterD.Value,app.OverallwheelbaseL.Value, xofsection,tdivtmax,fdivfmax,l,rdivR,bdivD,PdivD,tdivb,fdivb,thets,xl,psi,gamma,alpha);
                xf=reshape(xf,[],1);
                yf=reshape(yf,[],1);
                zf=reshape(zf,[],1);
                xb=reshape(xb,[],1);
                yb=reshape(yb,[],1);
                zb=reshape(zb,[],1);

                pointsf=[xf,yf,zf];
                pointsb=[xb,yb,zb];
                points=[pointsf;pointsb];
                % roration
                % Number of rotations
                RotationsNum=app.Numberofbladesz.Value-1;
                for i=1:RotationsNum
                    theta_deg = 360/app.Numberofbladesz.Value*i;

                    theta_rad = deg2rad(theta_deg);


                    Rx = [1      0           0;
                        0 cos(theta_rad) -sin(theta_rad);
                        0 sin(theta_rad)  cos(theta_rad)];

                    new_points{i} = (Rx * points')';
                end
                cla(app.UIAxesPre)
                hold(app.UIAxesPre,"on")
                scatter3(app.UIAxesPre, points(:,1),points(:,2),points(:,3),1,"blue","filled","o")
                cla(app.UIAxesXY)
                hold(app.UIAxesXY,"on")
                scatter3(app.UIAxesXY, points(:,1),points(:,2),points(:,3),1,"blue","filled","o")
                cla(app.UIAxesYZ)
                hold(app.UIAxesYZ,"on")
                scatter3(app.UIAxesYZ, points(:,1),points(:,2),points(:,3),1,"blue","filled","o")
                for i=1:RotationsNum
                    scatter3(app.UIAxesPre, new_points{i}(:,1),new_points{i}(:,2),new_points{i}(:,3),1,"blue","filled","o")
                    scatter3(app.UIAxesXY, new_points{i}(:,1),new_points{i}(:,2),new_points{i}(:,3),1,"blue","filled","o")
                    scatter3(app.UIAxesYZ, new_points{i}(:,1),new_points{i}(:,2),new_points{i}(:,3),1,"blue","filled","o")
                end

            catch
                msgbox("Plaese check the improt data")
            end

        end

        % Button pushed function: ShowinSection
        function ShowinSectionButtonPushed(app, event)
            try
                datasection=app.SectionInfo.Data;
                xofsection=datasection{:,1}';
                tdivtmax=datasection{:,2}';
                fdivfmax=datasection{:,3}';
                dataRadial=app.Radialinfo.Data;
                l=dataRadial{:,1}';
                rdivR=dataRadial{:,2}';
                bdivD=dataRadial{:,3}';
                PdivD=dataRadial{:,4}';
                tdivb=dataRadial{:,5}';
                fdivb=dataRadial{:,6}';
                thets=dataRadial{:,7}';
                xl=dataRadial{:,8}';
                psi=dataRadial{:,9}';
                gamma=dataRadial{:,10}';
                alpha=dataRadial{:,11}';
                [xf,yf,zf,xb,yb,zb] = GetPropPoint_offsets(app.DiameterD.Value,app.OverallwheelbaseL.Value, xofsection,tdivtmax,fdivfmax,l,rdivR,bdivD,PdivD,tdivb,fdivb,thets,xl,psi,gamma,alpha);
                xf=reshape(xf',[],1);
                yf=reshape(yf',[],1);
                zf=reshape(zf',[],1);
                xb=reshape(xb',[],1);
                yb=reshape(yb',[],1);
                zb=reshape(zb',[],1);
                pointsf=[xf,yf,zf];
                pointsb=[xb,yb,zb];
                points=[pointsf;pointsb];
                % roration
                % Number of rotations
                RotationsNum=app.Numberofbladesz.Value-1;
                for i=1:RotationsNum
                    theta_deg = 360/app.Numberofbladesz.Value*i;

                    theta_rad = deg2rad(theta_deg);


                    Rx = [1      0           0;
                        0 cos(theta_rad) -sin(theta_rad);
                        0 sin(theta_rad)  cos(theta_rad)];


                    new_points{i} = (Rx * points')';
                end
                count=1;
                cla(app.UIAxesPre)
                cla(app.UIAxesXY)
                cla(app.UIAxesYZ)
                for i=1:2
                    for j=1:length(l)
                        for k=1:length(xofsection)
                            x(k)=points(count,1);
                            y(k)=points(count,2);
                            z(k)=points(count,3);
                            count=count+1;
                        end
                        hold(app.UIAxesPre,"on")
                        plot3(app.UIAxesPre, x,y,z,LineWidth=0.5,Color='b')
                        hold(app.UIAxesXY,"on")
                        plot3(app.UIAxesXY, x,y,z,LineWidth=0.5,Color='b')
                        hold(app.UIAxesYZ,"on")
                        plot3(app.UIAxesYZ, x,y,z,LineWidth=0.5,Color='b')
                    end
                end

                for Ni=1:RotationsNum
                    count=1;
                    for i=1:2
                        for j=1:length(l)
                            for k=1:length(xofsection)
                                x(k)=new_points{Ni}(count,1);
                                y(k)=new_points{Ni}(count,2);
                                z(k)=new_points{Ni}(count,3);
                                count=count+1;
                            end
                            hold(app.UIAxesPre,"on")
                            plot3(app.UIAxesPre, x,y,z,LineWidth=0.5,Color='b')
                            hold(app.UIAxesXY,"on")
                            plot3(app.UIAxesXY, x,y,z,LineWidth=0.5,Color='b')
                            hold(app.UIAxesYZ,"on")
                            plot3(app.UIAxesYZ, x,y,z,LineWidth=0.5,Color='b')
                        end
                    end

                end
            catch
                msgbox("Plaese check the improt data")
            end

        end

        % Button pushed function: ShowinSurface
        function ShowinSurfaceButtonPushed(app, event)
            try
                datasection=app.SectionInfo.Data;
                xofsection=datasection{:,1}';
                tdivtmax=datasection{:,2}';
                fdivfmax=datasection{:,3}';
                dataRadial=app.Radialinfo.Data;
                l=dataRadial{:,1}';
                rdivR=dataRadial{:,2}';
                bdivD=dataRadial{:,3}';
                PdivD=dataRadial{:,4}';
                tdivb=dataRadial{:,5}';
                fdivb=dataRadial{:,6}';
                thets=dataRadial{:,7}';
                xl=dataRadial{:,8}';
                psi=dataRadial{:,9}';
                gamma=dataRadial{:,10}';
                alpha=dataRadial{:,11}';
                [xf,yf,zf,xb,yb,zb] = GetPropPoint_offsets(app.DiameterD.Value,app.OverallwheelbaseL.Value, xofsection,tdivtmax,fdivfmax,l,rdivR,bdivD,PdivD,tdivb,fdivb,thets,xl,psi,gamma,alpha);
                xf=reshape(xf',[],1);
                yf=reshape(yf',[],1);
                zf=reshape(zf',[],1);
                xb=reshape(xb',[],1);
                yb=reshape(yb',[],1);
                zb=reshape(zb',[],1);
                pointsf=[xf,yf,zf];
                pointsb=[xb,yb,zb];
                points=[pointsf;pointsb];
                % roration
                % Number of rotations
                RotationsNum=app.Numberofbladesz.Value-1;
                for i=1:RotationsNum
                    theta_deg = 360/app.Numberofbladesz.Value*i;

                    theta_rad = deg2rad(theta_deg);


                    Rx = [1      0           0;
                        0 cos(theta_rad) -sin(theta_rad);
                        0 sin(theta_rad)  cos(theta_rad)];


                    new_points{i} = (Rx * points')';
                end
                count=1;
                cla(app.UIAxesPre)
                cla(app.UIAxesXY)
                cla(app.UIAxesYZ)
                for i=1:2
                    for j=1:length(l)
                        for k=1:length(xofsection)
                            x(j,k)=points(count,1);
                            y(j,k)=points(count,2);
                            z(j,k)=points(count,3);
                            count=count+1;
                        end
                    end
                    hold(app.UIAxesPre,"on")
                    surf(app.UIAxesPre, x,y,z,EdgeColor="none")
                    hold(app.UIAxesXY,"on")
                    surf(app.UIAxesXY, x,y,z,EdgeColor="none")
                    hold(app.UIAxesYZ,"on")
                    surf(app.UIAxesYZ, x,y,z,EdgeColor="none")
                end

                for Ni=1:RotationsNum
                    count=1;
                    for i=1:2
                        for j=1:length(l)
                            for k=1:length(xofsection)
                                x(j,k)=new_points{Ni}(count,1);
                                y(j,k)=new_points{Ni}(count,2);
                                z(j,k)=new_points{Ni}(count,3);
                                count=count+1;
                            end
                        end
                        hold(app.UIAxesPre,"on")
                        surf(app.UIAxesPre, x,y,z,EdgeColor="none")
                        hold(app.UIAxesXY,"on")
                        surf(app.UIAxesXY, x,y,z,EdgeColor="none")
                        hold(app.UIAxesYZ,"on")
                        surf(app.UIAxesYZ, x,y,z,EdgeColor="none")
                    end

                end
            catch
                msgbox("Plaese check the improt data")
            end
        end

        % Button pushed function: Export
        function ExportButtonPushed(app, event)
            try
                datasection=app.SectionInfo.Data;
                xofsection=datasection{:,1}';
                tdivtmax=datasection{:,2}';
                fdivfmax=datasection{:,3}';
                dataRadial=app.Radialinfo.Data;
                l=dataRadial{:,1}';
                rdivR=dataRadial{:,2}';
                bdivD=dataRadial{:,3}';
                PdivD=dataRadial{:,4}';
                tdivb=dataRadial{:,5}';
                fdivb=dataRadial{:,6}';
                thets=dataRadial{:,7}';
                xl=dataRadial{:,8}';
                psi=dataRadial{:,9}';
                gamma=dataRadial{:,10}';
                alpha=dataRadial{:,11}';
                [xf,yf,zf,xb,yb,zb] = GetPropPoint_offsets(app.DiameterD.Value,app.OverallwheelbaseL.Value, xofsection,tdivtmax,fdivfmax,l,rdivR,bdivD,PdivD,tdivb,fdivb,thets,xl,psi,gamma,alpha);
                xf=reshape(xf,[],1);
                yf=reshape(yf,[],1);
                zf=reshape(zf,[],1);
                xb=reshape(xb,[],1);
                yb=reshape(yb,[],1);
                zb=reshape(zb,[],1);

                pointsf=[xf,yf,zf];
                pointsb=[xb,yb,zb];
                points=[pointsf;pointsb];
                % roration
                % Number of rotations
                RotationsNum=app.Numberofbladesz.Value-1;
                for i=1:RotationsNum
                    theta_deg = 360/app.Numberofbladesz.Value*i;

                    theta_rad = deg2rad(theta_deg);


                    Rx = [1      0           0;
                        0 cos(theta_rad) -sin(theta_rad);
                        0 sin(theta_rad)  cos(theta_rad)];

                    new_points{i} = (Rx * points')';
                end
                [filename, pathname] = uiputfile({'*.txt'; '*.csv'}, 'Save As...');
                if isequal(filename,0) || isequal(pathname,0)
                    disp('User canceled save operation.');
                    return;
                end

                fullpath = fullfile(pathname, filename);

                fid = fopen(fullpath, 'w');
                if fid == -1
                    uialert(app.UIFigure, 'Failed to open file for writing.', 'File Error');
                    return;
                end
                try

                    fprintf(fid, "_SrfPtGrid\n");
                    fprintf(fid, "%d\n", length(xofsection));
                    fprintf(fid, "%d\n", length(l));

                    for i = 1:length(points(:,1))
                        if i == floor(length(points(:,1))/2) + 1
                            fprintf(fid, "_SrfPtGrid\n");
                            fprintf(fid, "%d\n", length(xofsection));
                            fprintf(fid, "%d\n", length(l));
                        end
                        fprintf(fid, '%.4f,%.4f,%.4f\n',points(i,1), points(i,2), points(i,3));
                    end
                    for Ni=1:RotationsNum
                        fprintf(fid, "_SrfPtGrid\n");
                        fprintf(fid, "%d\n", length(xofsection));
                        fprintf(fid, "%d\n", length(l));
                        for i = 1:length(new_points{Ni}(:,1))
                            if i == floor(length(new_points{Ni}(:,1))/2) + 1
                                fprintf(fid, "_SrfPtGrid\n");
                                fprintf(fid, "%d\n", length(xofsection));
                                fprintf(fid, "%d\n", length(l));
                            end
                            fprintf(fid, '%.4f,%.4f,%.4f\n',new_points{Ni}(i,1), new_points{Ni}(i,2), new_points{Ni}(i,3));
                        end
                    end
                end
                fclose(fid);

            catch
                msgbox("Plaese check the improt data")
            end
        end

        % Button pushed function: ShowHydrofoil
        function ShowHydrofoilButtonPushed(app, event)
            [coord] = CST_Parametric(app.weightoflowerEditField.Value,app.weightofupperEditField.Value,app.trailingedgethicknessEditField.Value,app.NumberofpointsEditField.Value);
            cla(app.FigSectionInfo);
            % Updata the FigSectionInfo
            hold(app.FigSectionInfo,"on")
            plot(app.FigSectionInfo, coord(1:app.NumberofpointsEditField.Value/2+1,1), coord(1:app.NumberofpointsEditField.Value/2+1,2),LineStyle="-",LineWidth=1.5,Color="r");
            plot(app.FigSectionInfo, coord(app.NumberofpointsEditField.Value/2+1:app.NumberofpointsEditField.Value+1,1), coord(app.NumberofpointsEditField.Value/2+1:app.NumberofpointsEditField.Value+1,2),LineStyle="-",LineWidth=1.5,Color="b");
            legend(app.FigSectionInfo,'lowersuface','uppersurface');
            xlabel(app.FigSectionInfo,"Chord")
            ylabel(app.FigSectionInfo,"Y")
        end

        % Value changed function: xEditField, xEditField_2, xEditField_3, 
        % ...and 13 other components
        function xEditFieldValueChanged(app, event)
            if  app.AddControlpointCheckBox.Value==1


            else
                ctrl_pts_upper=[app.xEditField.Value,app.yEditField.Value;...
                    app.xEditField_2.Value,app.yEditField_2.Value;...
                    app.xEditField_3.Value,app.yEditField_3.Value;...
                    app.xEditField_4.Value,app.yEditField_4.Value;];
                ctrl_pts_lower=[app.xEditField_5.Value,app.yEditField_5.Value;...
                    app.xEditField_6.Value,app.yEditField_6.Value;...
                    app.xEditField_7.Value,app.yEditField_7.Value;...
                    app.xEditField_8.Value,app.yEditField_8.Value;];
                curve_upper= BuildBspline(ctrl_pts_upper, 3,1000);
                curve_lower= BuildBspline(ctrl_pts_lower, 3,1000);
                cla(app.FigSectionInfo);
                % Updata the FigSectionInfo
                hold(app.FigSectionInfo,"on")
                plot(app.FigSectionInfo, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                plot(app.FigSectionInfo, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                plot(app.FigSectionInfo, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                plot(app.FigSectionInfo, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                legend(app.FigSectionInfo,'Control Polygon','Hydorfoil');
                xlabel(app.FigSectionInfo,"Chord")
                ylabel(app.FigSectionInfo,"Y")
            end
        end

        % Value changed function: AddControlpointCheckBox
        function AddControlpointCheckBoxValueChanged(app, event)
            if  app.AddControlpointCheckBox.Value==1
                app.UITable.Data= table([app.xEditField.Value;app.xEditField_2.Value;app.xEditField_3.Value;app.xEditField_4.Value],...
                    [app.yEditField.Value;app.yEditField_2.Value;app.yEditField_3.Value;app.yEditField_4.Value]);
                app.UITable_2.Data=table([app.xEditField_5.Value;app.xEditField_6.Value;app.xEditField_7.Value;app.xEditField_8.Value],...
                    [app.yEditField_5.Value;app.yEditField_6.Value;app.yEditField_7.Value;app.yEditField_8.Value]);

                app.UITable.ColumnName={'Xctrl_pts_upper', 'Yctrl_pts_upper'};
                app.UITable_2.ColumnName={'Xctrl_pts_lower', 'Yctrl_pts_lower'};
                app.yEditField_9.Editable="on";
                app.yEditField_9.Enable="on";
                app.xEditField_9.Editable="on";
                app.xEditField_9.Enable="on";
            else
                app.yEditField_9.Editable="off";
                app.yEditField_9.Enable="off";
                app.xEditField_9.Editable="off";
                app.xEditField_9.Enable="off";
            end

        end

        % Button pushed function: ShowHydrofoil_2
        function ShowHydrofoil_2ButtonPushed(app, event)

            % Get values from edit fields
            x = app.xEditField_9.Value;
            y = app.yEditField_9.Value;

            % Current table data
            T = app.UITable.Data;

            % Append new row
            newRow = table(x, y,'VariableNames', T.Properties.VariableNames);
            T = [T; newRow];

            % Sort by first column (X)
            T = sortrows(T, T.Properties.VariableNames(1));

            % Update table
            app.UITable.Data = T;
            app.xEditField_9.Value=0;
            app.yEditField_9.Value=0;
            ctrl_pts_upper=table2array(app.UITable.Data);
            ctrl_pts_lower=table2array(app.UITable.Data);
            curve_upper= BuildBspline(ctrl_pts_upper, 3,1000);
            curve_lower= BuildBspline(ctrl_pts_lower, 3,1000);
            cla(app.FigSectionInfo);
            % Updata the FigSectionInfo
            hold(app.FigSectionInfo,"on")
            plot(app.FigSectionInfo, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
            plot(app.FigSectionInfo, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
            plot(app.FigSectionInfo, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
            plot(app.FigSectionInfo, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
            scatter(app.FigSectionInfo, x, y,"ro");
            legend(app.FigSectionInfo,'Control Polygon','Hydorfoil');
            xlabel(app.FigSectionInfo,"Chord")
            ylabel(app.FigSectionInfo,"Y")
        end

        % Button pushed function: ShowHydrofoil_3
        function ShowHydrofoil_3ButtonPushed(app, event)

            % Get values from edit fields
            x = app.xEditField_9.Value;
            y = app.yEditField_9.Value;

            % Current table data
            T = app.UITable_2.Data;

            % Append new row
            newRow = table(x, y,'VariableNames', T.Properties.VariableNames);
            T = [T; newRow];

            % Sort by first column (X)
            T = sortrows(T, T.Properties.VariableNames(1));

            % Update table
            app.UITable_2.Data = T;
            app.xEditField_9.Value=0;
            app.yEditField_9.Value=0;
            ctrl_pts_upper=table2array(app.UITable_2.Data);
            ctrl_pts_lower=table2array(app.UITable_2.Data);
            curve_upper= BuildBspline(ctrl_pts_upper, 3,1000);
            curve_lower= BuildBspline(ctrl_pts_lower, 3,1000);
            cla(app.FigSectionInfo);
            % Updata the FigSectionInfo
            hold(app.FigSectionInfo,"on")
            plot(app.FigSectionInfo, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
            plot(app.FigSectionInfo, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
            plot(app.FigSectionInfo, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
            plot(app.FigSectionInfo, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
            scatter(app.FigSectionInfo, x, y,"ro");
            legend(app.FigSectionInfo,'Control Polygon','Hydorfoil');
            xlabel(app.FigSectionInfo,"Chord")
            ylabel(app.FigSectionInfo,"Y")
        end

        % Button pushed function: ShowHydrofoil_4
        function ShowHydrofoil_4ButtonPushed(app, event)
            % Get current table data
            T = app.UITable.Data;

            % Get selected cell (row, column)
            selected = app.UITable.Selection;

            % If no selection, show alert and return
            if isempty(selected)
                uialert(app.UIFigure, 'Please select a row to delete.', 'No Selection');
                return;
            end

            % Extract selected row index (only first selection used)
            rowToDelete = selected(1);

            % Delete the row from the table
            T(rowToDelete, :) = [];

            % Update the UITable with new data
            app.UITable.Data = T;
            app.xEditField_9.Value=0;
            app.yEditField_9.Value=0;
            ctrl_pts_upper=table2array(app.UITable.Data);
            ctrl_pts_lower=table2array(app.UITable.Data);
            curve_upper= BuildBspline(ctrl_pts_upper, 3,1000);
            curve_lower= BuildBspline(ctrl_pts_lower, 3,1000);
            cla(app.FigSectionInfo);
            % Updata the FigSectionInfo
            hold(app.FigSectionInfo,"on")
            plot(app.FigSectionInfo, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
            plot(app.FigSectionInfo, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
            plot(app.FigSectionInfo, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
            plot(app.FigSectionInfo, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
            legend(app.FigSectionInfo,'Control Polygon','Hydorfoil');
            xlabel(app.FigSectionInfo,"Chord")
            ylabel(app.FigSectionInfo,"Y")
        end

        % Button pushed function: ShowHydrofoil_5
        function ShowHydrofoil_5ButtonPushed(app, event)
            % Get current table data
            T = app.UITable_2.Data;

            % Get selected cell (row, column)
            selected = app.UITable_2.Selection;

            % If no selection, show alert and return
            if isempty(selected)
                uialert(app.UIFigure, 'Please select a row to delete.', 'No Selection');
                return;
            end

            % Extract selected row index (only first selection used)
            rowToDelete = selected(1);

            % Delete the row from the table
            T(rowToDelete, :) = [];

            % Update the UITable with new data
            app.UITable_2.Data = T;
            app.xEditField_9.Value=0;
            app.yEditField_9.Value=0;
            ctrl_pts_upper=table2array(app.UITable_2.Data);
            ctrl_pts_lower=table2array(app.UITable_2.Data);
            curve_upper= BuildBspline(ctrl_pts_upper, 3,1000);
            curve_lower= BuildBspline(ctrl_pts_lower, 3,1000);
            cla(app.FigSectionInfo);
            % Updata the FigSectionInfo
            hold(app.FigSectionInfo,"on")
            plot(app.FigSectionInfo, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
            plot(app.FigSectionInfo, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
            plot(app.FigSectionInfo, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
            plot(app.FigSectionInfo, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
            legend(app.FigSectionInfo,'Control Polygon','Hydorfoil');
            xlabel(app.FigSectionInfo,"Chord")
            ylabel(app.FigSectionInfo,"Y")
        end

        % Value changed function: xEditField_11, xEditField_12, 
        % ...and 14 other components
        function xEditField_11ValueChanged(app, event)
            if  app.AddControlpointCheckBox_2.Value==1


            else
                ctrl_pts_upper=[app.xEditField_11.Value,app.yEditField_11.Value;...
                    app.xEditField_12.Value,app.yEditField_12.Value;...
                    app.xEditField_13.Value,app.yEditField_13.Value;...
                    app.xEditField_14.Value,app.yEditField_14.Value;];
                ctrl_pts_lower=[app.xEditField_15.Value,app.yEditField_15.Value;...
                    app.xEditField_16.Value,app.yEditField_16.Value;...
                    app.xEditField_17.Value,app.yEditField_17.Value;...
                    app.xEditField_18.Value,app.yEditField_18.Value;];
                curve_upper= BuildBspline(ctrl_pts_upper, 3,1000);
                curve_lower= BuildBspline(ctrl_pts_lower, 3,1000);

                switch app.ParametersDropDown.Value
                    case "l/L"
                        cla(app.UIAxesl);
                        % Updata the FigSectionInfo
                        hold(app.UIAxesl,"on")
                        plot(app.UIAxesl, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                        plot(app.UIAxesl, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                        plot(app.UIAxesl, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                        plot(app.UIAxesl, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    case "r/R"
                        cla(app.UIAxesl);
                        % Updata the FigSectionInfo
                        hold(app.UIAxesrR,"on")
                        plot(app.UIAxesrR, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                        plot(app.UIAxesrR, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                        plot(app.UIAxesrR, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                        plot(app.UIAxesrR, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    case "c/D"
                        cla(app.UIAxescD);
                        % Updata the FigSectionInfo
                        hold(app.UIAxescD,"on")
                        plot(app.UIAxescD, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                        plot(app.UIAxescD, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                        plot(app.UIAxescD, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                        plot(app.UIAxescD, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    case "p/D"
                        cla(app.UIAxesPD);
                        % Updata the FigSectionInfo
                        hold(app.UIAxesPD,"on")
                        plot(app.UIAxesPD, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                        plot(app.UIAxesPD, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                        plot(app.UIAxesPD, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                        plot(app.UIAxesPD, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    case "tmax/c"
                        cla(app.UIAxestmaxC);
                        % Updata the FigSectionInfo
                        hold(app.UIAxestmaxC,"on")
                        plot(app.UIAxestmaxC, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                        plot(app.UIAxestmaxC, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                        plot(app.UIAxestmaxC, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                        plot(app.UIAxestmaxC, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    case "fmax/c"
                        cla(app.UIAxesfmaxC);
                        % Updata the FigSectionInfo
                        hold(app.UIAxesfmaxC,"on")
                        plot(app.UIAxesfmaxC, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                        plot(app.UIAxesfmaxC, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                        plot(app.UIAxesfmaxC, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                        plot(app.UIAxesfmaxC, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    case "angleSkew"
                        cla(app.UIAxesangleSkew);
                        % Updata the FigSectionInfo
                        hold(app.UIAxesangleSkew,"on")
                        plot(app.UIAxesangleSkew, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                        plot(app.UIAxesangleSkew, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                        plot(app.UIAxesangleSkew, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                        plot(app.UIAxesangleSkew, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    case "Rake"
                        cla(app.UIAxesRake);
                        % Updata the FigSectionInfo
                        hold(app.UIAxesRake,"on")
                        plot(app.UIAxesRake, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                        plot(app.UIAxesRake, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                        plot(app.UIAxesRake, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                        plot(app.UIAxesRake, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    case "Lateral Angle"
                        cla(app.UIAxesLateral   );
                        % Updata the FigSectionInfo
                        hold(app.UIAxesLateral,"on")
                        plot(app.UIAxesLateral, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                        plot(app.UIAxesLateral, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                        plot(app.UIAxesLateral, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                        plot(app.UIAxesLateral, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    case "Roll Angle"
                        cla(app.UIAxesRoll);
                        % Updata the FigSectionInfo
                        hold(app.UIAxesRoll,"on")
                        plot(app.UIAxesRoll, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                        plot(app.UIAxesRoll, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                        plot(app.UIAxesRoll, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                        plot(app.UIAxesRoll, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    case "Vertical Angle"
                        cla(app.UIAxesVertical);
                        % Updata the FigSectionInfo
                        hold(app.UIAxesVertical,"on")
                        plot(app.UIAxesVertical, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                        plot(app.UIAxesVertical, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                        plot(app.UIAxesVertical, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                        plot(app.UIAxesVertical, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");

                end
            end

        end

        % Value changed function: AddControlpointCheckBox_2
        function AddControlpointCheckBox_2ValueChanged(app, event)
            if  app.AddControlpointCheckBox_2.Value==1
                app.UITable_4.Data= table([app.xEditField_11.Value;app.xEditField_12.Value;app.xEditField_13.Value;app.xEditField_14.Value],...
                    [app.yEditField_11.Value;app.yEditField_12.Value;app.yEditField_13.Value;app.yEditField_14.Value]);
                app.UITable_3.Data=table([app.xEditField_15.Value;app.xEditField_16.Value;app.xEditField_17.Value;app.xEditField_18.Value],...
                    [app.yEditField_15.Value;app.yEditField_16.Value;app.yEditField_17.Value;app.yEditField_18.Value]);
                app.yEditField_10.Editable="on";
                app.yEditField_10.Enable="on";
                app.xEditField_10.Editable="on";
                app.xEditField_10.Enable="on";
            else
                app.yEditField_10.Editable="off";
                app.yEditField_10.Enable="off";
                app.xEditField_10.Editable="off";
                app.xEditField_10.Enable="off";
            end

        end

        % Button pushed function: ShowHydrofoil_6
        function ShowHydrofoil_6ButtonPushed(app, event)

            % Get values from edit fields
            x = app.xEditField_10.Value;
            y = app.yEditField_10.Value;

            % Current table data
            T = app.UITable_4.Data;

            % Append new row
            newRow = table(x, y,'VariableNames', T.Properties.VariableNames);
            T = [T; newRow];

            % Sort by first column (X)
            T = sortrows(T, T.Properties.VariableNames(2));

            % Update table
            app.UITable_4.Data = T;
            app.xEditField_10.Value=0;
            app.yEditField_10.Value=0;
            ctrl_pts_upper=table2array(app.UITable_4.Data);
            ctrl_pts_lower=table2array(app.UITable_3.Data);
            curve_upper= BuildBspline(ctrl_pts_upper, 3,1000);
            curve_lower= BuildBspline(ctrl_pts_lower, 3,1000);
            switch app.ParametersDropDown.Value
                case "l/L"
                    cla(app.UIAxesl);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesl,"on")
                    plot(app.UIAxesl, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesl, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesl, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesl, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "r/R"
                    cla(app.UIAxesl);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesrR,"on")
                    plot(app.UIAxesrR, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesrR, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesrR, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesrR, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "c/D"
                    cla(app.UIAxescD);
                    % Updata the FigSectionInfo
                    hold(app.UIAxescD,"on")
                    plot(app.UIAxescD, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxescD, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxescD, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxescD, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "p/D"
                    cla(app.UIAxesPD);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesPD,"on")
                    plot(app.UIAxesPD, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesPD, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesPD, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesPD, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "tmax/c"
                    cla(app.UIAxestmaxC);
                    % Updata the FigSectionInfo
                    hold(app.UIAxestmaxC,"on")
                    plot(app.UIAxestmaxC, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxestmaxC, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxestmaxC, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxestmaxC, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "fmax/c"
                    cla(app.UIAxesfmaxC);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesfmaxC,"on")
                    plot(app.UIAxesfmaxC, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesfmaxC, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesfmaxC, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesfmaxC, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "angleSkew"
                    cla(app.UIAxesangleSkew);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesangleSkew,"on")
                    plot(app.UIAxesangleSkew, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesangleSkew, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesangleSkew, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesangleSkew, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Rake"
                    cla(app.UIAxesRake);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesRake,"on")
                    plot(app.UIAxesRake, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesRake, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesRake, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesRake, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Lateral Angle"
                    cla(app.UIAxesLateral   );
                    % Updata the FigSectionInfo
                    hold(app.UIAxesLateral,"on")
                    plot(app.UIAxesLateral, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesLateral, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesLateral, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesLateral, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Roll Angle"
                    cla(app.UIAxesRoll);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesRoll,"on")
                    plot(app.UIAxesRoll, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesRoll, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesRoll, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesRoll, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Vertical Angle"
                    cla(app.UIAxesVertical);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesVertical,"on")
                    plot(app.UIAxesVertical, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesVertical, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesVertical, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesVertical, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");

            end
        end

        % Button pushed function: ShowHydrofoil_8
        function ShowHydrofoil_8ButtonPushed(app, event)
            % Get current table data
            T = app.UITable_4.Data;

            % Get selected cell (row, column)
            selected = app.UITable_4.Selection;

            % If no selection, show alert and return
            if isempty(selected)
                uialert(app.UIFigure, 'Please select a row to delete.', 'No Selection');
                return;
            end

            % Extract selected row index (only first selection used)
            rowToDelete = selected(1);

            % Delete the row from the table
            T(rowToDelete, :) = [];

            % Update the UITable with new data
            app.UITable_4.Data = T;
            app.xEditField_10.Value=0;
            app.yEditField_10.Value=0;
            ctrl_pts_upper=table2array(app.UITable_4.Data);
            ctrl_pts_lower=table2array(app.UITable_4.Data);
            curve_upper= BuildBspline(ctrl_pts_upper, 3,1000);
            curve_lower= BuildBspline(ctrl_pts_lower, 3,1000);
            switch app.ParametersDropDown.Value
                case "l/L"
                    cla(app.UIAxesl);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesl,"on")
                    plot(app.UIAxesl, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesl, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesl, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesl, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "r/R"
                    cla(app.UIAxesl);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesrR,"on")
                    plot(app.UIAxesrR, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesrR, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesrR, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesrR, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "c/D"
                    cla(app.UIAxescD);
                    % Updata the FigSectionInfo
                    hold(app.UIAxescD,"on")
                    plot(app.UIAxescD, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxescD, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxescD, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxescD, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "p/D"
                    cla(app.UIAxesPD);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesPD,"on")
                    plot(app.UIAxesPD, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesPD, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesPD, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesPD, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "tmax/c"
                    cla(app.UIAxestmaxC);
                    % Updata the FigSectionInfo
                    hold(app.UIAxestmaxC,"on")
                    plot(app.UIAxestmaxC, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxestmaxC, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxestmaxC, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxestmaxC, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "fmax/c"
                    cla(app.UIAxesfmaxC);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesfmaxC,"on")
                    plot(app.UIAxesfmaxC, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesfmaxC, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesfmaxC, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesfmaxC, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "angleSkew"
                    cla(app.UIAxesangleSkew);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesangleSkew,"on")
                    plot(app.UIAxesangleSkew, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesangleSkew, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesangleSkew, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesangleSkew, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Rake"
                    cla(app.UIAxesRake);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesRake,"on")
                    plot(app.UIAxesRake, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesRake, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesRake, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesRake, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Lateral Angle"
                    cla(app.UIAxesLateral   );
                    % Updata the FigSectionInfo
                    hold(app.UIAxesLateral,"on")
                    plot(app.UIAxesLateral, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesLateral, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesLateral, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesLateral, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Roll Angle"
                    cla(app.UIAxesRoll);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesRoll,"on")
                    plot(app.UIAxesRoll, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesRoll, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesRoll, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesRoll, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Vertical Angle"
                    cla(app.UIAxesVertical);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesVertical,"on")
                    plot(app.UIAxesVertical, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesVertical, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesVertical, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesVertical, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");

            end
        end

        % Button pushed function: ShowHydrofoil_7
        function ShowHydrofoil_7ButtonPushed(app, event)
            % Get values from edit fields
            x = app.xEditField_10.Value;
            y = app.yEditField_10.Value;

            % Current table data
            T = app.UITable_3.Data;

            % Append new row
            newRow = table(x, y,'VariableNames', T.Properties.VariableNames);
            T = [T; newRow];

            % Sort by first column (X)
            T = sortrows(T, T.Properties.VariableNames(2));

            % Update table
            app.UITable_3.Data = T;
            app.xEditField_10.Value=0;
            app.yEditField_10.Value=0;
            ctrl_pts_upper=table2array(app.UITable_4.Data);
            ctrl_pts_lower=table2array(app.UITable_3.Data);
            curve_upper= BuildBspline(ctrl_pts_upper, 3,1000);
            curve_lower= BuildBspline(ctrl_pts_lower, 3,1000);
            switch app.ParametersDropDown.Value
                case "l/L"
                    cla(app.UIAxesl);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesl,"on")
                    plot(app.UIAxesl, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesl, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesl, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesl, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "r/R"
                    cla(app.UIAxesl);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesrR,"on")
                    plot(app.UIAxesrR, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesrR, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesrR, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesrR, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "c/D"
                    cla(app.UIAxescD);
                    % Updata the FigSectionInfo
                    hold(app.UIAxescD,"on")
                    plot(app.UIAxescD, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxescD, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxescD, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxescD, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "p/D"
                    cla(app.UIAxesPD);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesPD,"on")
                    plot(app.UIAxesPD, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesPD, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesPD, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesPD, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "tmax/c"
                    cla(app.UIAxestmaxC);
                    % Updata the FigSectionInfo
                    hold(app.UIAxestmaxC,"on")
                    plot(app.UIAxestmaxC, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxestmaxC, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxestmaxC, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxestmaxC, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "fmax/c"
                    cla(app.UIAxesfmaxC);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesfmaxC,"on")
                    plot(app.UIAxesfmaxC, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesfmaxC, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesfmaxC, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesfmaxC, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "angleSkew"
                    cla(app.UIAxesangleSkew);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesangleSkew,"on")
                    plot(app.UIAxesangleSkew, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesangleSkew, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesangleSkew, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesangleSkew, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Rake"
                    cla(app.UIAxesRake);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesRake,"on")
                    plot(app.UIAxesRake, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesRake, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesRake, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesRake, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Lateral Angle"
                    cla(app.UIAxesLateral   );
                    % Updata the FigSectionInfo
                    hold(app.UIAxesLateral,"on")
                    plot(app.UIAxesLateral, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesLateral, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesLateral, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesLateral, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Roll Angle"
                    cla(app.UIAxesRoll);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesRoll,"on")
                    plot(app.UIAxesRoll, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesRoll, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesRoll, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesRoll, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Vertical Angle"
                    cla(app.UIAxesVertical);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesVertical,"on")
                    plot(app.UIAxesVertical, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesVertical, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesVertical, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesVertical, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");

            end
        end

        % Button pushed function: ShowHydrofoil_9
        function ShowHydrofoil_9ButtonPushed(app, event)
            % Get current table data
            T = app.UITable_3.Data;

            % Get selected cell (row, column)
            selected = app.UITable_3.Selection;

            % If no selection, show alert and return
            if isempty(selected)
                uialert(app.UIFigure, 'Please select a row to delete.', 'No Selection');
                return;
            end

            % Extract selected row index (only first selection used)
            rowToDelete = selected(1);

            % Delete the row from the table
            T(rowToDelete, :) = [];

            % Update the UITable with new data
            app.UITable_3.Data = T;
            app.xEditField_10.Value=0;
            app.yEditField_10.Value=0;
            ctrl_pts_upper=table2array(app.UITable_3.Data);
            ctrl_pts_lower=table2array(app.UITable_3.Data);
            curve_upper= BuildBspline(ctrl_pts_upper, 3,1000);
            curve_lower= BuildBspline(ctrl_pts_lower, 3,1000);
            switch app.ParametersDropDown.Value
                case "l/L"
                    cla(app.UIAxesl);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesl,"on")
                    plot(app.UIAxesl, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesl, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesl, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesl, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "r/R"
                    cla(app.UIAxesl);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesrR,"on")
                    plot(app.UIAxesrR, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesrR, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesrR, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesrR, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "c/D"
                    cla(app.UIAxescD);
                    % Updata the FigSectionInfo
                    hold(app.UIAxescD,"on")
                    plot(app.UIAxescD, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxescD, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxescD, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxescD, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "p/D"
                    cla(app.UIAxesPD);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesPD,"on")
                    plot(app.UIAxesPD, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesPD, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesPD, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesPD, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "tmax/c"
                    cla(app.UIAxestmaxC);
                    % Updata the FigSectionInfo
                    hold(app.UIAxestmaxC,"on")
                    plot(app.UIAxestmaxC, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxestmaxC, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxestmaxC, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxestmaxC, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "fmax/c"
                    cla(app.UIAxesfmaxC);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesfmaxC,"on")
                    plot(app.UIAxesfmaxC, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesfmaxC, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesfmaxC, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesfmaxC, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "angleSkew"
                    cla(app.UIAxesangleSkew);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesangleSkew,"on")
                    plot(app.UIAxesangleSkew, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesangleSkew, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesangleSkew, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesangleSkew, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Rake"
                    cla(app.UIAxesRake);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesRake,"on")
                    plot(app.UIAxesRake, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesRake, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesRake, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesRake, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Lateral Angle"
                    cla(app.UIAxesLateral   );
                    % Updata the FigSectionInfo
                    hold(app.UIAxesLateral,"on")
                    plot(app.UIAxesLateral, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesLateral, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesLateral, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesLateral, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Roll Angle"
                    cla(app.UIAxesRoll);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesRoll,"on")
                    plot(app.UIAxesRoll, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesRoll, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesRoll, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesRoll, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                case "Vertical Angle"
                    cla(app.UIAxesVertical);
                    % Updata the FigSectionInfo
                    hold(app.UIAxesVertical,"on")
                    plot(app.UIAxesVertical, ctrl_pts_upper(:,1), ctrl_pts_upper(:,2), 'k--o');
                    plot(app.UIAxesVertical, curve_upper(:,1), curve_upper(:,2),LineStyle="-",LineWidth=1.5,Color="b");
                    plot(app.UIAxesVertical, ctrl_pts_lower(:,1), ctrl_pts_lower(:,2), 'k--o');
                    plot(app.UIAxesVertical, curve_lower(:,1), curve_lower(:,2),LineStyle="-",LineWidth=1.5,Color="b");

            end
        end

        % Button pushed function: ShowinPoints_2
        function ShowinPoints_2ButtonPushed(app, event)
            xofsection=linspace(0,1,app.UEditField.Value);
            rR=linspace(app.HubdiameterratioRhRE_2.Value,1,app.VEditField.Value);

            children = app.FigSectionInfo.Children;
            if length(children)==4
                xlower = children(1).XData;
                ylower = children(1).YData;
                xupper = children(3).XData;
                yupper = children(3).YData;
            else
                xlower = children(2).XData;
                ylower = children(2).YData;
                xupper = children(1).XData;
                yupper = children(1).YData;
            end
            yfs=interp1(xlower,ylower,xofsection);
            ybs=interp1(xupper,yupper,xofsection);
            children = app.UIAxesl.Children;
            lLfore=children(3).XData;
            rRlLfore=children(3).YData;
            lLback=children(1).XData;
            rRlLback=children(1).YData;
            l=[interp1(rRlLfore,lLfore,rR),flip(interp1(rRlLback,lLback,rR))];

            children = app.UIAxescD.Children;
            cDfore=children(3).XData;
            rRcDfore=children(3).YData;
            cDback=children(1).XData;
            rRcDback=children(1).YData;
            bdivD=[interp1(rRcDfore,cDfore,rR),flip(interp1(rRcDback,cDback,rR))];

            children = app.UIAxesPD.Children;
            PDfore=children(3).XData;
            rRPDfore=children(3).YData;
            PDback=children(1).XData;
            rRPDback=children(1).YData;
            PdivD=[interp1(rRPDfore,PDfore,rR),flip(interp1(rRPDback,PDback,rR))];

            children = app.UIAxesangleSkew.Children;
            angleSkewfore=children(3).XData;
            rRangleSkewfore=children(3).YData;
            angleSkewback=children(1).XData;
            rRangleSkewback=children(1).YData;
            thets=[interp1(rRangleSkewfore,angleSkewfore,rR),flip(interp1(rRangleSkewback,angleSkewback,rR))];

            children = app.UIAxesRake.Children;
            Rakefore=children(3).XData;
            rRRakefore=children(3).YData;
            Rakeback=children(1).XData;
            rRRakeback=children(1).YData;
            xl=[interp1(rRRakefore,Rakefore,rR),flip(interp1(rRRakeback,Rakeback,rR))];

            children = app.UIAxesLateral.Children;
            Lateralfore=children(3).XData;
            rRLateralfore=children(3).YData;
            Lateralback=children(1).XData;
            rRLateralback=children(1).YData;
            psi=[interp1(rRLateralfore,Lateralfore,rR),flip(interp1(rRLateralback,Lateralback,rR))];


            children = app.UIAxesRoll.Children;
            Rollfore=children(3).XData;
            rRRollfore=children(3).YData;
            Rollback=children(1).XData;
            rRRollback=children(1).YData;
            gamma=[interp1(rRRollfore,Rollfore,rR),flip(interp1(rRRollback,Rollback,rR))];

            children = app.UIAxesVertical.Children;
            Verticalfore=children(3).XData;
            rRVerticalfore=children(3).YData;
            Verticalback=children(1).XData;
            rRVerticalback=children(1).YData;
            alpha=[interp1(rRVerticalfore,Verticalfore,rR),flip(interp1(rRVerticalback,Verticalback,rR))];
            rR=[rR,flip(rR)];

            cla(app.UIAxesrR);
            plot(app.UIAxesrR, rR, rR,LineStyle="-",LineWidth=1.5,Color="r");
            tmax=max(ybs-yfs);
            fmax=max((ybs-yfs)/2);
            cla(app.UIAxestmaxC);
            plot(app.UIAxestmaxC, rR, tmax*ones(1,length(rR)),LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxesfmaxC);
            plot(app.UIAxesfmaxC, rR, fmax*ones(1,length(rR)),LineStyle="-",LineWidth=1.5,Color="r");
            try
                [xf,yf,zf,xb,yb,zb] = GetPropPoint_para(app.DiameterD_2.Value,app.OverallwheelbaseL_2.Value, xofsection,ybs,yfs,l,rR,bdivD,PdivD,thets,xl,psi,gamma,alpha);
                xf=reshape(xf,[],1);
                yf=reshape(yf,[],1);
                zf=reshape(zf,[],1);
                xb=reshape(xb,[],1);
                yb=reshape(yb,[],1);
                zb=reshape(zb,[],1);

                pointsf=[xf,yf,zf];
                pointsb=[xb,yb,zb];
                points=[pointsf;pointsb];
                % roration
                % Number of rotations
                RotationsNum=app.Numberofbladesz_2.Value-1;
                for i=1:RotationsNum
                    theta_deg = 360/app.Numberofbladesz_2.Value*i;

                    theta_rad = deg2rad(theta_deg);


                    Rx = [1      0           0;
                        0 cos(theta_rad) -sin(theta_rad);
                        0 sin(theta_rad)  cos(theta_rad)];

                    new_points{i} = (Rx * points')';
                end
                cla(app.UIAxesPre)
                hold(app.UIAxesPre,"on")
                scatter3(app.UIAxesPre, points(:,1),points(:,2),points(:,3),1,"blue","filled","o")
                cla(app.UIAxesXY)
                hold(app.UIAxesXY,"on")
                scatter3(app.UIAxesXY, points(:,1),points(:,2),points(:,3),1,"blue","filled","o")
                cla(app.UIAxesYZ)
                hold(app.UIAxesYZ,"on")
                scatter3(app.UIAxesYZ, points(:,1),points(:,2),points(:,3),1,"blue","filled","o")
                for i=1:RotationsNum
                    scatter3(app.UIAxesPre, new_points{i}(:,1),new_points{i}(:,2),new_points{i}(:,3),1,"blue","filled","o")
                    scatter3(app.UIAxesXY, new_points{i}(:,1),new_points{i}(:,2),new_points{i}(:,3),1,"blue","filled","o")
                    scatter3(app.UIAxesYZ, new_points{i}(:,1),new_points{i}(:,2),new_points{i}(:,3),1,"blue","filled","o")
                end

            catch
                msgbox("Plaese check the improt data")
            end


        end

        % Button pushed function: ShowinSection_2
        function ShowinSection_2ButtonPushed(app, event)
            xofsection=linspace(0,1,app.UEditField.Value);
            rR=linspace(app.HubdiameterratioRhRE_2.Value,1,app.VEditField.Value);

            children = app.FigSectionInfo.Children;
            if length(children)==4
                xlower = children(1).XData;
                ylower = children(1).YData;
                xupper = children(3).XData;
                yupper = children(3).YData;
            else
                xlower = children(2).XData;
                ylower = children(2).YData;
                xupper = children(1).XData;
                yupper = children(1).YData;
            end
            yfs=interp1(xlower,ylower,xofsection);
            ybs=interp1(xupper,yupper,xofsection);
            children = app.UIAxesl.Children;
            lLfore=children(3).XData;
            rRlLfore=children(3).YData;
            lLback=children(1).XData;
            rRlLback=children(1).YData;
            l=[interp1(rRlLfore,lLfore,rR),flip(interp1(rRlLback,lLback,rR))];

            children = app.UIAxescD.Children;
            cDfore=children(3).XData;
            rRcDfore=children(3).YData;
            cDback=children(1).XData;
            rRcDback=children(1).YData;
            bdivD=[interp1(rRcDfore,cDfore,rR),flip(interp1(rRcDback,cDback,rR))];

            children = app.UIAxesPD.Children;
            PDfore=children(3).XData;
            rRPDfore=children(3).YData;
            PDback=children(1).XData;
            rRPDback=children(1).YData;
            PdivD=[interp1(rRPDfore,PDfore,rR),flip(interp1(rRPDback,PDback,rR))];

            children = app.UIAxesangleSkew.Children;
            angleSkewfore=children(3).XData;
            rRangleSkewfore=children(3).YData;
            angleSkewback=children(1).XData;
            rRangleSkewback=children(1).YData;
            thets=[interp1(rRangleSkewfore,angleSkewfore,rR),flip(interp1(rRangleSkewback,angleSkewback,rR))];

            children = app.UIAxesRake.Children;
            Rakefore=children(3).XData;
            rRRakefore=children(3).YData;
            Rakeback=children(1).XData;
            rRRakeback=children(1).YData;
            xl=[interp1(rRRakefore,Rakefore,rR),flip(interp1(rRRakeback,Rakeback,rR))];

            children = app.UIAxesLateral.Children;
            Lateralfore=children(3).XData;
            rRLateralfore=children(3).YData;
            Lateralback=children(1).XData;
            rRLateralback=children(1).YData;
            psi=[interp1(rRLateralfore,Lateralfore,rR),flip(interp1(rRLateralback,Lateralback,rR))];


            children = app.UIAxesRoll.Children;
            Rollfore=children(3).XData;
            rRRollfore=children(3).YData;
            Rollback=children(1).XData;
            rRRollback=children(1).YData;
            gamma=[interp1(rRRollfore,Rollfore,rR),flip(interp1(rRRollback,Rollback,rR))];

            children = app.UIAxesVertical.Children;
            Verticalfore=children(3).XData;
            rRVerticalfore=children(3).YData;
            Verticalback=children(1).XData;
            rRVerticalback=children(1).YData;
            alpha=[interp1(rRVerticalfore,Verticalfore,rR),flip(interp1(rRVerticalback,Verticalback,rR))];
            rR=[rR,flip(rR)];

            cla(app.UIAxesrR);
            plot(app.UIAxesrR, rR, rR,LineStyle="-",LineWidth=1.5,Color="r");
            tmax=max(ybs-yfs);
            fmax=max((ybs-yfs)/2);
            cla(app.UIAxestmaxC);
            plot(app.UIAxestmaxC, rR, tmax*ones(1,length(rR)),LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxesfmaxC);
            plot(app.UIAxesfmaxC, rR, fmax*ones(1,length(rR)),LineStyle="-",LineWidth=1.5,Color="r");
            try
                [xf,yf,zf,xb,yb,zb] = GetPropPoint_para(app.DiameterD_2.Value,app.OverallwheelbaseL_2.Value, xofsection,ybs,yfs,l,rR,bdivD,PdivD,thets,xl,psi,gamma,alpha);
                xf=reshape(xf',[],1);
                yf=reshape(yf',[],1);
                zf=reshape(zf',[],1);
                xb=reshape(xb',[],1);
                yb=reshape(yb',[],1);
                zb=reshape(zb',[],1);
                pointsf=[xf,yf,zf];
                pointsb=[xb,yb,zb];
                points=[pointsf;pointsb];
                % roration
                % Number of rotations
                RotationsNum=app.Numberofbladesz_2.Value-1;
                for i=1:RotationsNum
                    theta_deg = 360/app.Numberofbladesz_2.Value*i;

                    theta_rad = deg2rad(theta_deg);


                    Rx = [1      0           0;
                        0 cos(theta_rad) -sin(theta_rad);
                        0 sin(theta_rad)  cos(theta_rad)];


                    new_points{i} = (Rx * points')';
                end
                count=1;
                cla(app.UIAxesPre)
                cla(app.UIAxesXY)
                cla(app.UIAxesYZ)
                for i=1:2
                    for j=1:length(l)
                        for k=1:length(xofsection)
                            x(k)=points(count,1);
                            y(k)=points(count,2);
                            z(k)=points(count,3);
                            count=count+1;
                        end
                        hold(app.UIAxesPre,"on")
                        plot3(app.UIAxesPre, x,y,z,LineWidth=0.5,Color='b')
                        hold(app.UIAxesXY,"on")
                        plot3(app.UIAxesXY, x,y,z,LineWidth=0.5,Color='b')
                        hold(app.UIAxesYZ,"on")
                        plot3(app.UIAxesYZ, x,y,z,LineWidth=0.5,Color='b')
                    end
                end

                for Ni=1:RotationsNum
                    count=1;
                    for i=1:2
                        for j=1:length(l)
                            for k=1:length(xofsection)
                                x(k)=new_points{Ni}(count,1);
                                y(k)=new_points{Ni}(count,2);
                                z(k)=new_points{Ni}(count,3);
                                count=count+1;
                            end
                            hold(app.UIAxesPre,"on")
                            plot3(app.UIAxesPre, x,y,z,LineWidth=0.5,Color='b')
                            hold(app.UIAxesXY,"on")
                            plot3(app.UIAxesXY, x,y,z,LineWidth=0.5,Color='b')
                            hold(app.UIAxesYZ,"on")
                            plot3(app.UIAxesYZ, x,y,z,LineWidth=0.5,Color='b')
                        end
                    end

                end
            catch
                msgbox("Plaese check the improt data")
            end
        end

        % Button pushed function: ShowinSurface_2
        function ShowinSurface_2ButtonPushed(app, event)
            xofsection=linspace(0,1,app.UEditField.Value);
            rR=linspace(app.HubdiameterratioRhRE_2.Value,1,app.VEditField.Value);

            children = app.FigSectionInfo.Children;
            if length(children)==4
                xlower = children(1).XData;
                ylower = children(1).YData;
                xupper = children(3).XData;
                yupper = children(3).YData;
            else
                xlower = children(2).XData;
                ylower = children(2).YData;
                xupper = children(1).XData;
                yupper = children(1).YData;
            end
            yfs=interp1(xlower,ylower,xofsection);
            ybs=interp1(xupper,yupper,xofsection);
            children = app.UIAxesl.Children;
            lLfore=children(3).XData;
            rRlLfore=children(3).YData;
            lLback=children(1).XData;
            rRlLback=children(1).YData;
            l=[interp1(rRlLfore,lLfore,rR),flip(interp1(rRlLback,lLback,rR))];

            children = app.UIAxescD.Children;
            cDfore=children(3).XData;
            rRcDfore=children(3).YData;
            cDback=children(1).XData;
            rRcDback=children(1).YData;
            bdivD=[interp1(rRcDfore,cDfore,rR),flip(interp1(rRcDback,cDback,rR))];

            children = app.UIAxesPD.Children;
            PDfore=children(3).XData;
            rRPDfore=children(3).YData;
            PDback=children(1).XData;
            rRPDback=children(1).YData;
            PdivD=[interp1(rRPDfore,PDfore,rR),flip(interp1(rRPDback,PDback,rR))];

            children = app.UIAxesangleSkew.Children;
            angleSkewfore=children(3).XData;
            rRangleSkewfore=children(3).YData;
            angleSkewback=children(1).XData;
            rRangleSkewback=children(1).YData;
            thets=[interp1(rRangleSkewfore,angleSkewfore,rR),flip(interp1(rRangleSkewback,angleSkewback,rR))];

            children = app.UIAxesRake.Children;
            Rakefore=children(3).XData;
            rRRakefore=children(3).YData;
            Rakeback=children(1).XData;
            rRRakeback=children(1).YData;
            xl=[interp1(rRRakefore,Rakefore,rR),flip(interp1(rRRakeback,Rakeback,rR))];

            children = app.UIAxesLateral.Children;
            Lateralfore=children(3).XData;
            rRLateralfore=children(3).YData;
            Lateralback=children(1).XData;
            rRLateralback=children(1).YData;
            psi=[interp1(rRLateralfore,Lateralfore,rR),flip(interp1(rRLateralback,Lateralback,rR))];


            children = app.UIAxesRoll.Children;
            Rollfore=children(3).XData;
            rRRollfore=children(3).YData;
            Rollback=children(1).XData;
            rRRollback=children(1).YData;
            gamma=[interp1(rRRollfore,Rollfore,rR),flip(interp1(rRRollback,Rollback,rR))];

            children = app.UIAxesVertical.Children;
            Verticalfore=children(3).XData;
            rRVerticalfore=children(3).YData;
            Verticalback=children(1).XData;
            rRVerticalback=children(1).YData;
            alpha=[interp1(rRVerticalfore,Verticalfore,rR),flip(interp1(rRVerticalback,Verticalback,rR))];
            rR=[rR,flip(rR)];

            cla(app.UIAxesrR);
            plot(app.UIAxesrR, rR, rR,LineStyle="-",LineWidth=1.5,Color="r");
            tmax=max(ybs-yfs);
            fmax=max((ybs-yfs)/2);
            cla(app.UIAxestmaxC);
            plot(app.UIAxestmaxC, rR, tmax*ones(1,length(rR)),LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxesfmaxC);
            plot(app.UIAxesfmaxC, rR, fmax*ones(1,length(rR)),LineStyle="-",LineWidth=1.5,Color="r");
            try
                [xf,yf,zf,xb,yb,zb] = GetPropPoint_para(app.DiameterD_2.Value,app.OverallwheelbaseL_2.Value, xofsection,ybs,yfs,l,rR,bdivD,PdivD,thets,xl,psi,gamma,alpha);
                xf=reshape(xf',[],1);
                yf=reshape(yf',[],1);
                zf=reshape(zf',[],1);
                xb=reshape(xb',[],1);
                yb=reshape(yb',[],1);
                zb=reshape(zb',[],1);
                pointsf=[xf,yf,zf];
                pointsb=[xb,yb,zb];
                points=[pointsf;pointsb];
                % roration
                % Number of rotations
                RotationsNum=app.Numberofbladesz_2.Value-1;
                for i=1:RotationsNum
                    theta_deg = 360/app.Numberofbladesz_2.Value*i;

                    theta_rad = deg2rad(theta_deg);


                    Rx = [1      0           0;
                        0 cos(theta_rad) -sin(theta_rad);
                        0 sin(theta_rad)  cos(theta_rad)];


                    new_points{i} = (Rx * points')';
                end
                count=1;
                cla(app.UIAxesPre)
                cla(app.UIAxesXY)
                cla(app.UIAxesYZ)
                for i=1:2
                    for j=1:length(l)
                        for k=1:length(xofsection)
                            x(j,k)=points(count,1);
                            y(j,k)=points(count,2);
                            z(j,k)=points(count,3);
                            count=count+1;
                        end
                    end
                    hold(app.UIAxesPre,"on")
                    surf(app.UIAxesPre, x,y,z,EdgeColor="none")
                    hold(app.UIAxesXY,"on")
                    surf(app.UIAxesXY, x,y,z,EdgeColor="none")
                    hold(app.UIAxesYZ,"on")
                    surf(app.UIAxesYZ, x,y,z,EdgeColor="none")
                end

                for Ni=1:RotationsNum
                    count=1;
                    for i=1:2
                        for j=1:length(l)
                            for k=1:length(xofsection)
                                x(j,k)=new_points{Ni}(count,1);
                                y(j,k)=new_points{Ni}(count,2);
                                z(j,k)=new_points{Ni}(count,3);
                                count=count+1;
                            end
                        end
                        hold(app.UIAxesPre,"on")
                        surf(app.UIAxesPre, x,y,z,EdgeColor="none")
                        hold(app.UIAxesXY,"on")
                        surf(app.UIAxesXY, x,y,z,EdgeColor="none")
                        hold(app.UIAxesYZ,"on")
                        surf(app.UIAxesYZ, x,y,z,EdgeColor="none")
                    end

                end
            catch
                msgbox("Plaese check the improt data")
            end
        end

        % Button pushed function: Export_2
        function Export_2ButtonPushed(app, event)
            xofsection=linspace(0,1,app.UEditField.Value);
            rR=linspace(app.HubdiameterratioRhRE_2.Value,1,app.VEditField.Value);

            children = app.FigSectionInfo.Children;
            if length(children)==4
                xlower = children(1).XData;
                ylower = children(1).YData;
                xupper = children(3).XData;
                yupper = children(3).YData;
            else
                xlower = children(2).XData;
                ylower = children(2).YData;
                xupper = children(1).XData;
                yupper = children(1).YData;
            end
            yfs=interp1(xlower,ylower,xofsection);
            ybs=interp1(xupper,yupper,xofsection);
            children = app.UIAxesl.Children;
            lLfore=children(3).XData;
            rRlLfore=children(3).YData;
            lLback=children(1).XData;
            rRlLback=children(1).YData;
            l=[interp1(rRlLfore,lLfore,rR),flip(interp1(rRlLback,lLback,rR))];
            l(length(l)/2+1)=[];
            children = app.UIAxescD.Children;
            cDfore=children(3).XData;
            rRcDfore=children(3).YData;
            cDback=children(1).XData;
            rRcDback=children(1).YData;
            bdivD=[interp1(rRcDfore,cDfore,rR),flip(interp1(rRcDback,cDback,rR))];
            bdivD(length(bdivD)/2+1)=[];
            children = app.UIAxesPD.Children;
            PDfore=children(3).XData;
            rRPDfore=children(3).YData;
            PDback=children(1).XData;
            rRPDback=children(1).YData;
            PdivD=[interp1(rRPDfore,PDfore,rR),flip(interp1(rRPDback,PDback,rR))];
            PdivD(length(PdivD)/2+1)=[];

            children = app.UIAxesangleSkew.Children;
            angleSkewfore=children(3).XData;
            rRangleSkewfore=children(3).YData;
            angleSkewback=children(1).XData;
            rRangleSkewback=children(1).YData;
            thets=[interp1(rRangleSkewfore,angleSkewfore,rR),flip(interp1(rRangleSkewback,angleSkewback,rR))];
            thets(length(thets)/2+1)=[];

            children = app.UIAxesRake.Children;
            Rakefore=children(3).XData;
            rRRakefore=children(3).YData;
            Rakeback=children(1).XData;
            rRRakeback=children(1).YData;
            xl=[interp1(rRRakefore,Rakefore,rR),flip(interp1(rRRakeback,Rakeback,rR))];
            xl(length(xl)/2+1)=[];

            children = app.UIAxesLateral.Children;
            Lateralfore=children(3).XData;
            rRLateralfore=children(3).YData;
            Lateralback=children(1).XData;
            rRLateralback=children(1).YData;
            psi=[interp1(rRLateralfore,Lateralfore,rR),flip(interp1(rRLateralback,Lateralback,rR))];
            psi(length(psi)/2+1)=[];

            children = app.UIAxesRoll.Children;
            Rollfore=children(3).XData;
            rRRollfore=children(3).YData;
            Rollback=children(1).XData;
            rRRollback=children(1).YData;
            gamma=[interp1(rRRollfore,Rollfore,rR),flip(interp1(rRRollback,Rollback,rR))];
            gamma(length(gamma)/2+1)=[];

            children = app.UIAxesVertical.Children;
            Verticalfore=children(3).XData;
            rRVerticalfore=children(3).YData;
            Verticalback=children(1).XData;
            rRVerticalback=children(1).YData;
            alpha=[interp1(rRVerticalfore,Verticalfore,rR),flip(interp1(rRVerticalback,Verticalback,rR))];
            alpha(length(alpha)/2+1)=[];

            rR=[rR,flip(rR(1:end-1))];

            cla(app.UIAxesrR);
            plot(app.UIAxesrR, rR, rR,LineStyle="-",LineWidth=1.5,Color="r");
            tmax=max(ybs-yfs);
            fmax=max((ybs-yfs)/2);
            cla(app.UIAxestmaxC);
            plot(app.UIAxestmaxC, rR, tmax*ones(1,length(rR)),LineStyle="-",LineWidth=1.5,Color="r");
            cla(app.UIAxesfmaxC);
            plot(app.UIAxesfmaxC, rR, fmax*ones(1,length(rR)),LineStyle="-",LineWidth=1.5,Color="r");
            try
                [xf,yf,zf,xb,yb,zb] = GetPropPoint_para(app.DiameterD_2.Value,app.OverallwheelbaseL_2.Value, xofsection,ybs,yfs,l,rR,bdivD,PdivD,thets,xl,psi,gamma,alpha);
                xf=reshape(xf,[],1);
                yf=reshape(yf,[],1);
                zf=reshape(zf,[],1);
                xb=reshape(xb,[],1);
                yb=reshape(yb,[],1);
                zb=reshape(zb,[],1);

                pointsf=[xf,yf,zf];
                pointsb=[xb,yb,zb];
                points=[pointsf;pointsb];
                % roration
                % Number of rotations
                RotationsNum=app.Numberofbladesz_2.Value-1;
                for i=1:RotationsNum
                    theta_deg = 360/app.Numberofbladesz_2.Value*i;

                    theta_rad = deg2rad(theta_deg);


                    Rx = [1      0           0;
                        0 cos(theta_rad) -sin(theta_rad);
                        0 sin(theta_rad)  cos(theta_rad)];

                    new_points{i} = (Rx * points')';
                end
                [filename, pathname] = uiputfile({'*.txt'; '*.csv'}, 'Save As...');
                if isequal(filename,0) || isequal(pathname,0)
                    disp('User canceled save operation.');
                    return;
                end

                fullpath = fullfile(pathname, filename);

                fid = fopen(fullpath, 'w');
                if fid == -1
                    uialert(app.UIFigure, 'Failed to open file for writing.', 'File Error');
                    return;
                end
                try

                    fprintf(fid, "_SrfPtGrid\n");
                    fprintf(fid, "%d\n", length(xofsection));
                    fprintf(fid, "%d\n", length(l));

                    for i = 1:length(points(:,1))
                        if i == floor(length(points(:,1))/2) + 1
                            fprintf(fid, "_SrfPtGrid\n");
                            fprintf(fid, "%d\n", length(xofsection));
                            fprintf(fid, "%d\n", length(l));
                        end
                        fprintf(fid, '%.4f,%.4f,%.4f\n',points(i,1), points(i,2), points(i,3));
                    end
                    for Ni=1:RotationsNum
                        fprintf(fid, "_SrfPtGrid\n");
                        fprintf(fid, "%d\n", length(xofsection));
                        fprintf(fid, "%d\n", length(l));
                        for i = 1:length(new_points{Ni}(:,1))
                            if i == floor(length(new_points{Ni}(:,1))/2) + 1
                                fprintf(fid, "_SrfPtGrid\n");
                                fprintf(fid, "%d\n", length(xofsection));
                                fprintf(fid, "%d\n", length(l));
                            end
                            fprintf(fid, '%.4f,%.4f,%.4f\n',new_points{Ni}(i,1), new_points{Ni}(i,2), new_points{Ni}(i,3));
                        end
                    end
                end
                fclose(fid);

            catch
                msgbox("Plaese check the improt data")
            end
        end

        % Value changed function: ParametersDropDown
        function ParametersDropDownValueChanged(app, event)
            app.xEditField_11.Value=0;
            app.yEditField_11.Value=0;
            app.xEditField_12.Value=0;
            app.yEditField_12.Value=0;
            app.xEditField_13.Value=0;
            app.yEditField_13.Value=0;
            app.xEditField_14.Value=0;
            app.yEditField_14.Value=0;
            app.xEditField_15.Value=0;
            app.yEditField_15.Value=0;
            app.xEditField_16.Value=0;
            app.yEditField_16.Value=0;
            app.xEditField_17.Value=0;
            app.yEditField_17.Value=0;
            app.xEditField_18.Value=0;
            app.yEditField_18.Value=0;
                                    
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PlatToroidalPropv10UIFigure = uifigure('Visible', 'off');
            app.PlatToroidalPropv10UIFigure.Name = 'PlatToroidalProp v1.0';
            app.PlatToroidalPropv10UIFigure.Icon = fullfile(pathToMLAPP, 'Doc', 'Icon.jpg');
            app.FigSectionInfo = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.FigSectionInfo, 'SectionInfo')
            xlabel(app.FigSectionInfo, 'S/C')
            ylabel(app.FigSectionInfo, 't/tmax & f/fmax')
            zlabel(app.FigSectionInfo, 'Z')
            app.UIAxesl = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxesl, 'l/L')
            xlabel(app.UIAxesl, 'l/L')
            ylabel(app.UIAxesl, 'r/R')
            zlabel(app.UIAxesl, 'Z')
            app.UIAxesPre = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxesPre, 'Title')
            xlabel(app.UIAxesPre, 'X')
            ylabel(app.UIAxesPre, 'Y')
            zlabel(app.UIAxesPre, 'Z')
            app.UIAxesXY = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxesXY, 'Title')
            xlabel(app.UIAxesXY, 'X')
            ylabel(app.UIAxesXY, 'Y')
            zlabel(app.UIAxesXY, 'Z')
            app.UIAxesYZ = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxesYZ, 'Title')
            xlabel(app.UIAxesYZ, 'X')
            ylabel(app.UIAxesYZ, 'Y')
            zlabel(app.UIAxesYZ, 'Z')
            app.UIAxesrR = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxesrR, 'r/R')
            xlabel(app.UIAxesrR, 'r/R')
            ylabel(app.UIAxesrR, 'r/R')
            zlabel(app.UIAxesrR, 'Z')
            app.UIAxescD = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxescD, 'c/D')
            xlabel(app.UIAxescD, 'c/D')
            ylabel(app.UIAxescD, 'r/R')
            zlabel(app.UIAxescD, 'Z')
            app.UIAxestmaxC = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxestmaxC, 'tmax/c')
            xlabel(app.UIAxestmaxC, 'tmax/c')
            ylabel(app.UIAxestmaxC, 'r/R')
            zlabel(app.UIAxestmaxC, 'Z')
            app.UIAxesfmaxC = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxesfmaxC, 'fmax/c')
            xlabel(app.UIAxesfmaxC, 'fmax/c')
            ylabel(app.UIAxesfmaxC, 'r/R')
            zlabel(app.UIAxesfmaxC, 'Z')
            app.UIAxesangleSkew = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxesangleSkew, 'angleSkew')
            xlabel(app.UIAxesangleSkew, 'angleSkew')
            ylabel(app.UIAxesangleSkew, 'r/R')
            zlabel(app.UIAxesangleSkew, 'Z')
            app.UIAxesVertical = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxesVertical, 'Vertical Angle')
            xlabel(app.UIAxesVertical, 'Vertical Angle')
            ylabel(app.UIAxesVertical, 'r/R')
            zlabel(app.UIAxesVertical, 'Z')
            app.UIAxesRoll = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxesRoll, 'Roll Angle')
            xlabel(app.UIAxesRoll, 'Roll Angle')
            ylabel(app.UIAxesRoll, 'r/R')
            zlabel(app.UIAxesRoll, 'Z')
            app.UIAxesLateral = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxesLateral, 'Lateral Angle')
            xlabel(app.UIAxesLateral, 'Lateral Angle')
            ylabel(app.UIAxesLateral, 'r/R')
            zlabel(app.UIAxesLateral, 'Z')
            app.UIAxesPD = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxesPD, 'P/D')
            xlabel(app.UIAxesPD, 'P/D')
            ylabel(app.UIAxesPD, 'r/R')
            zlabel(app.UIAxesPD, 'Z')
            app.UIAxesRake = uiaxes(app.PlatToroidalPropv10UIFigure);
            title(app.UIAxesRake, 'Rake')
            xlabel(app.UIAxesRake, 'Rake')
            ylabel(app.UIAxesRake, 'r/R')
            zlabel(app.UIAxesRake, 'Z')
            app.TabGroup = uitabgroup(app.PlatToroidalPropv10UIFigure);
            app.OffsetsMethodTab = uitab(app.TabGroup);
            app.OffsetsMethodTab.Title = 'Offsets Method';
            app.Bladesectiondefination = uipanel(app.OffsetsMethodTab);
            app.Bladesectiondefination.Title = 'Blade section defination';
            app.ImportSection = uibutton(app.Bladesectiondefination, 'push');
            app.ImportSection.ButtonPushedFcn = createCallbackFcn(app, @ImportSectionButtonPushed, true);
            app.ImportSection.Text = 'Import';
            app.SectionInfo = uitable(app.OffsetsMethodTab);
            app.SectionInfo.ColumnName = {'s/c'; 't/tmax'; 'f/fmax'};
            app.SectionInfo.RowName = {};
            app.Radialparameterinformation = uipanel(app.OffsetsMethodTab);
            app.Radialparameterinformation.Title = 'Radial parameter information';
            app.ImportRadial = uibutton(app.Radialparameterinformation, 'push');
            app.ImportRadial.ButtonPushedFcn = createCallbackFcn(app, @ImportRadialButtonPushed, true);
            app.ImportRadial.Text = 'Import';
            app.Radialinfo = uitable(app.OffsetsMethodTab);
            app.Radialinfo.ColumnName = {'l/L'; 'r/R'; 'c/D'; 'P/D'; 'tmax/c'; 'fmax/c'; 'angleSkew'; 'Rake'; 'Lateral Angle'; 'Roll Angle'; 'Vertical Angle'};
            app.Radialinfo.RowName = {};
            app.Mainparameterinformation = uipanel(app.OffsetsMethodTab);
            app.Mainparameterinformation.Title = 'Main parameter information';
            app.OverallwheelbaseLLabel = uilabel(app.Mainparameterinformation);
            app.OverallwheelbaseLLabel.Text = 'Overall wheelbase (L):';
            app.OverallwheelbaseL = uieditfield(app.Mainparameterinformation, 'numeric');
            app.DiameterDLabel = uilabel(app.Mainparameterinformation);
            app.DiameterDLabel.Text = 'Diameter (D):';
            app.DiameterD = uieditfield(app.Mainparameterinformation, 'numeric');
            app.HubdiameterratioRhRLabel = uilabel(app.Mainparameterinformation);
            app.HubdiameterratioRhRLabel.Text = 'Hub diameter ratio (Rh/R):';
            app.HubdiameterratioRhRE = uieditfield(app.Mainparameterinformation, 'numeric');
            app.NumberofbladeszLabel = uilabel(app.Mainparameterinformation);
            app.NumberofbladeszLabel.Text = 'Number of blades (z):';
            app.Numberofbladesz = uieditfield(app.Mainparameterinformation, 'numeric');
            app.ShowinSection = uibutton(app.Mainparameterinformation, 'push');
            app.ShowinSection.ButtonPushedFcn = createCallbackFcn(app, @ShowinSectionButtonPushed, true);
            app.ShowinSection.Text = 'Show Section';
            app.ShowinSurface = uibutton(app.Mainparameterinformation, 'push');
            app.ShowinSurface.ButtonPushedFcn = createCallbackFcn(app, @ShowinSurfaceButtonPushed, true);
            app.ShowinSurface.Text = 'Show Surface';
            app.ShowinPoints = uibutton(app.Mainparameterinformation, 'push');
            app.ShowinPoints.ButtonPushedFcn = createCallbackFcn(app, @ShowinPointsButtonPushed, true);
            app.ShowinPoints.Text = 'Show Points';
            app.Export = uibutton(app.Mainparameterinformation, 'push');
            app.Export.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.Export.Text = 'Exprot';
            app.ParametricMethodTab = uitab(app.TabGroup);
            app.ParametricMethodTab.Title = 'Parametric Method';
            app.Bladesectiondefination_2 = uipanel(app.ParametricMethodTab);
            app.Bladesectiondefination_2.Title = 'Blade section defination';
            app.TabGroup2 = uitabgroup(app.Bladesectiondefination_2);
            app.CSTmethodTab = uitab(app.TabGroup2);
            app.CSTmethodTab.Title = 'CSTmethod';
            app.weightoflowerEditFieldLabel = uilabel(app.CSTmethodTab);
            app.weightoflowerEditFieldLabel.Text = 'weightoflower';
            app.weightoflowerEditField = uieditfield(app.CSTmethodTab, 'numeric');
            app.weightofupperEditFieldLabel = uilabel(app.CSTmethodTab);
            app.weightofupperEditFieldLabel.Text = 'weightofupper';
            app.weightofupperEditField = uieditfield(app.CSTmethodTab, 'numeric');
            app.trailingedgethicknessEditFieldLabel = uilabel(app.CSTmethodTab);
            app.trailingedgethicknessEditFieldLabel.Text = 'trailingedgethickness';
            app.trailingedgethicknessEditField = uieditfield(app.CSTmethodTab, 'numeric');
            app.NumberofpointsEditFieldLabel = uilabel(app.CSTmethodTab);
            app.NumberofpointsEditFieldLabel.Text = 'Numberofpoints';
            app.NumberofpointsEditField = uieditfield(app.CSTmethodTab, 'numeric');
            app.ShowHydrofoil = uibutton(app.CSTmethodTab, 'push');
            app.ShowHydrofoil.ButtonPushedFcn = createCallbackFcn(app, @ShowHydrofoilButtonPushed, true);
            app.ShowHydrofoil.Text = 'ShowHydrofoil';
            app.BsplinemethodTab = uitab(app.TabGroup2);
            app.BsplinemethodTab.Title = 'Bsplinemethod';
            app.Panel_2 = uipanel(app.BsplinemethodTab);
            app.xEditFieldLabel = uilabel(app.Panel_2);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.Panel_2, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.Panel_2);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.Panel_2, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField_2Label = uilabel(app.Panel_2);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.xEditField_2.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditField_2Label = uilabel(app.Panel_2);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.yEditField_2.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField_3Label = uilabel(app.Panel_2);
            app.xEditField_3Label.Text = 'x';
            app.xEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.xEditField_3.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditField_3Label = uilabel(app.Panel_2);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.yEditField_3.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField_4Label = uilabel(app.Panel_2);
            app.xEditField_4Label.Text = 'x';
            app.xEditField_4 = uieditfield(app.Panel_2, 'numeric');
            app.xEditField_4.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField_4.Value = 1;
            app.yEditField_4Label = uilabel(app.Panel_2);
            app.yEditField_4Label.Text = 'y';
            app.yEditField_4 = uieditfield(app.Panel_2, 'numeric');
            app.yEditField_4.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField_5Label = uilabel(app.Panel_2);
            app.xEditField_5Label.Text = 'x';
            app.xEditField_5 = uieditfield(app.Panel_2, 'numeric');
            app.xEditField_5.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditField_5Label = uilabel(app.Panel_2);
            app.yEditField_5Label.Text = 'y';
            app.yEditField_5 = uieditfield(app.Panel_2, 'numeric');
            app.yEditField_5.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField_6Label = uilabel(app.Panel_2);
            app.xEditField_6Label.Text = 'x';
            app.xEditField_6 = uieditfield(app.Panel_2, 'numeric');
            app.xEditField_6.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditField_6Label = uilabel(app.Panel_2);
            app.yEditField_6Label.Text = 'y';
            app.yEditField_6 = uieditfield(app.Panel_2, 'numeric');
            app.yEditField_6.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField_7Label = uilabel(app.Panel_2);
            app.xEditField_7Label.Text = 'x';
            app.xEditField_7 = uieditfield(app.Panel_2, 'numeric');
            app.xEditField_7.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditField_7Label = uilabel(app.Panel_2);
            app.yEditField_7Label.Text = 'y';
            app.yEditField_7 = uieditfield(app.Panel_2, 'numeric');
            app.yEditField_7.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField_8Label = uilabel(app.Panel_2);
            app.xEditField_8Label.Text = 'x';
            app.xEditField_8 = uieditfield(app.Panel_2, 'numeric');
            app.xEditField_8.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.xEditField_8.Value = 1;
            app.yEditField_8Label = uilabel(app.Panel_2);
            app.yEditField_8Label.Text = 'y';
            app.yEditField_8 = uieditfield(app.Panel_2, 'numeric');
            app.yEditField_8.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.AddControlpointCheckBox = uicheckbox(app.BsplinemethodTab);
            app.AddControlpointCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddControlpointCheckBoxValueChanged, true);
            app.AddControlpointCheckBox.Text = 'Add Control point';
            app.xEditField_9Label = uilabel(app.BsplinemethodTab);
            app.xEditField_9Label.Enable = 'off';
            app.xEditField_9Label.Text = 'x';
            app.xEditField_9 = uieditfield(app.BsplinemethodTab, 'numeric');
            app.xEditField_9.Editable = 'off';
            app.xEditField_9.Enable = 'off';
            app.yEditField_9Label = uilabel(app.BsplinemethodTab);
            app.yEditField_9Label.Enable = 'off';
            app.yEditField_9Label.Text = 'y';
            app.yEditField_9 = uieditfield(app.BsplinemethodTab, 'numeric');
            app.yEditField_9.Editable = 'off';
            app.yEditField_9.Enable = 'off';
            app.ShowHydrofoil_2 = uibutton(app.BsplinemethodTab, 'push');
            app.ShowHydrofoil_2.ButtonPushedFcn = createCallbackFcn(app, @ShowHydrofoil_2ButtonPushed, true);
            app.ShowHydrofoil_2.Text = 'Update upper';
            app.ShowHydrofoil_3 = uibutton(app.BsplinemethodTab, 'push');
            app.ShowHydrofoil_3.ButtonPushedFcn = createCallbackFcn(app, @ShowHydrofoil_3ButtonPushed, true);
            app.ShowHydrofoil_3.Text = 'Update lower';
            app.DefaultControlPointsLabel = uilabel(app.BsplinemethodTab);
            app.DefaultControlPointsLabel.Text = 'Default Control Points';
            app.ShowHydrofoil_4 = uibutton(app.BsplinemethodTab, 'push');
            app.ShowHydrofoil_4.ButtonPushedFcn = createCallbackFcn(app, @ShowHydrofoil_4ButtonPushed, true);
            app.ShowHydrofoil_4.Text = 'Del upper';
            app.ShowHydrofoil_5 = uibutton(app.BsplinemethodTab, 'push');
            app.ShowHydrofoil_5.ButtonPushedFcn = createCallbackFcn(app, @ShowHydrofoil_5ButtonPushed, true);
            app.ShowHydrofoil_5.Text = 'Del lower';
            app.UITable_2 = uitable(app.BsplinemethodTab);
            app.UITable_2.ColumnName = {'Xctrl_pts_lower'; 'Yctrl_pts_lower'};
            app.UITable_2.RowName = {};
            app.UITable = uitable(app.BsplinemethodTab);
            app.UITable.ColumnName = {'Xctrl_pts_upper'; 'Yctrl_pts_upper'};
            app.UITable.RowName = {};
            app.Radialparameterinformation_2 = uipanel(app.ParametricMethodTab);
            app.Radialparameterinformation_2.Title = 'Radial parameter information';
            app.ParametersDropDownLabel = uilabel(app.Radialparameterinformation_2);
            app.ParametersDropDownLabel.Text = 'Parameters';
            app.ParametersDropDown = uidropdown(app.Radialparameterinformation_2);
            app.ParametersDropDown.Items = {'l/L', 'c/D', 'p/D', 'angleSkew', 'Rake', 'Lateral Angle', 'Roll Angle', 'Vertical Angle'};
            app.ParametersDropDown.ValueChangedFcn = createCallbackFcn(app, @ParametersDropDownValueChanged, true);
            app.ParametersDropDown.Value = 'l/L';
            app.Panel_3 = uipanel(app.Radialparameterinformation_2);
            app.xEditField_11Label = uilabel(app.Panel_3);
            app.xEditField_11Label.Text = 'x';
            app.xEditField_11 = uieditfield(app.Panel_3, 'numeric');
            app.xEditField_11.ValueDisplayFormat = '%.3f';
            app.xEditField_11.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.yEditField_11Label = uilabel(app.Panel_3);
            app.yEditField_11Label.Text = 'y';
            app.yEditField_11 = uieditfield(app.Panel_3, 'numeric');
            app.yEditField_11.ValueDisplayFormat = '%.3f';
            app.yEditField_11.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.xEditField_12Label = uilabel(app.Panel_3);
            app.xEditField_12Label.Text = 'x';
            app.xEditField_12 = uieditfield(app.Panel_3, 'numeric');
            app.xEditField_12.ValueDisplayFormat = '%.3f';
            app.xEditField_12.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.yEditField_12Label = uilabel(app.Panel_3);
            app.yEditField_12Label.Text = 'y';
            app.yEditField_12 = uieditfield(app.Panel_3, 'numeric');
            app.yEditField_12.ValueDisplayFormat = '%.3f';
            app.yEditField_12.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.xEditField_13Label = uilabel(app.Panel_3);
            app.xEditField_13Label.Text = 'x';
            app.xEditField_13 = uieditfield(app.Panel_3, 'numeric');
            app.xEditField_13.ValueDisplayFormat = '%.3f';
            app.xEditField_13.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.yEditField_13Label = uilabel(app.Panel_3);
            app.yEditField_13Label.Text = 'y';
            app.yEditField_13 = uieditfield(app.Panel_3, 'numeric');
            app.yEditField_13.ValueDisplayFormat = '%.3f';
            app.yEditField_13.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.xEditField_14Label = uilabel(app.Panel_3);
            app.xEditField_14Label.Text = 'x';
            app.xEditField_14 = uieditfield(app.Panel_3, 'numeric');
            app.xEditField_14.ValueDisplayFormat = '%.3f';
            app.xEditField_14.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.xEditField_14.Value = 1;
            app.yEditField_14Label = uilabel(app.Panel_3);
            app.yEditField_14Label.Text = 'y';
            app.yEditField_14 = uieditfield(app.Panel_3, 'numeric');
            app.yEditField_14.ValueDisplayFormat = '%.3f';
            app.yEditField_14.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.xEditField_15Label = uilabel(app.Panel_3);
            app.xEditField_15Label.Text = 'x';
            app.xEditField_15 = uieditfield(app.Panel_3, 'numeric');
            app.xEditField_15.ValueDisplayFormat = '%.3f';
            app.xEditField_15.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.yEditField_15Label = uilabel(app.Panel_3);
            app.yEditField_15Label.Text = 'y';
            app.yEditField_15 = uieditfield(app.Panel_3, 'numeric');
            app.yEditField_15.ValueDisplayFormat = '%.3f';
            app.yEditField_15.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.xEditField_16Label = uilabel(app.Panel_3);
            app.xEditField_16Label.Text = 'x';
            app.xEditField_16 = uieditfield(app.Panel_3, 'numeric');
            app.xEditField_16.ValueDisplayFormat = '%.3f';
            app.xEditField_16.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.yEditField_16Label = uilabel(app.Panel_3);
            app.yEditField_16Label.Text = 'y';
            app.yEditField_16 = uieditfield(app.Panel_3, 'numeric');
            app.yEditField_16.ValueDisplayFormat = '%.3f';
            app.yEditField_16.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.xEditField_17Label = uilabel(app.Panel_3);
            app.xEditField_17Label.Text = 'x';
            app.xEditField_17 = uieditfield(app.Panel_3, 'numeric');
            app.xEditField_17.ValueDisplayFormat = '%.3f';
            app.xEditField_17.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.yEditField_17Label = uilabel(app.Panel_3);
            app.yEditField_17Label.Text = 'y';
            app.yEditField_17 = uieditfield(app.Panel_3, 'numeric');
            app.yEditField_17.ValueDisplayFormat = '%.3f';
            app.yEditField_17.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.xEditField_18Label = uilabel(app.Panel_3);
            app.xEditField_18Label.Text = 'x';
            app.xEditField_18 = uieditfield(app.Panel_3, 'numeric');
            app.xEditField_18.ValueDisplayFormat = '%.3f';
            app.xEditField_18.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.xEditField_18.Value = 1;
            app.yEditField_18Label = uilabel(app.Panel_3);
            app.yEditField_18Label.Text = 'y';
            app.yEditField_18 = uieditfield(app.Panel_3, 'numeric');
            app.yEditField_18.ValueDisplayFormat = '%.3f';
            app.yEditField_18.ValueChangedFcn = createCallbackFcn(app, @xEditField_11ValueChanged, true);
            app.AddControlpointCheckBox_2 = uicheckbox(app.Radialparameterinformation_2);
            app.AddControlpointCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AddControlpointCheckBox_2ValueChanged, true);
            app.AddControlpointCheckBox_2.Text = 'Add Control point';
            app.ShowHydrofoil_6 = uibutton(app.Radialparameterinformation_2, 'push');
            app.ShowHydrofoil_6.ButtonPushedFcn = createCallbackFcn(app, @ShowHydrofoil_6ButtonPushed, true);
            app.ShowHydrofoil_6.Text = 'Update fore';
            app.ShowHydrofoil_7 = uibutton(app.Radialparameterinformation_2, 'push');
            app.ShowHydrofoil_7.ButtonPushedFcn = createCallbackFcn(app, @ShowHydrofoil_7ButtonPushed, true);
            app.ShowHydrofoil_7.Text = 'Update back';
            app.DefaultControlPointsLabel_2 = uilabel(app.Radialparameterinformation_2);
            app.DefaultControlPointsLabel_2.Text = 'Default Control Points';
            app.ShowHydrofoil_8 = uibutton(app.Radialparameterinformation_2, 'push');
            app.ShowHydrofoil_8.ButtonPushedFcn = createCallbackFcn(app, @ShowHydrofoil_8ButtonPushed, true);
            app.ShowHydrofoil_8.Text = 'Del fore';
            app.ShowHydrofoil_9 = uibutton(app.Radialparameterinformation_2, 'push');
            app.ShowHydrofoil_9.ButtonPushedFcn = createCallbackFcn(app, @ShowHydrofoil_9ButtonPushed, true);
            app.ShowHydrofoil_9.Text = 'Del back';
            app.UITable_3 = uitable(app.Radialparameterinformation_2);
            app.UITable_3.ColumnName = {'Xctrl_pts_back'; 'Yctrl_pts_back'};
            app.UITable_3.RowName = {};
            app.UITable_4 = uitable(app.Radialparameterinformation_2);
            app.UITable_4.ColumnName = {'Xctrl_pts_fore'; 'Yctrl_pts_fore'};
            app.UITable_4.RowName = {};
            app.xEditField_10Label = uilabel(app.Radialparameterinformation_2);
            app.xEditField_10Label.Enable = 'off';
            app.xEditField_10Label.Text = 'x';
            app.xEditField_10 = uieditfield(app.Radialparameterinformation_2, 'numeric');
            app.xEditField_10.Editable = 'off';
            app.xEditField_10.Enable = 'off';
            app.yEditField_10Label = uilabel(app.Radialparameterinformation_2);
            app.yEditField_10Label.Enable = 'off';
            app.yEditField_10Label.Text = 'y';
            app.yEditField_10 = uieditfield(app.Radialparameterinformation_2, 'numeric');
            app.yEditField_10.Editable = 'off';
            app.yEditField_10.Enable = 'off';
            app.VEditFieldLabel = uilabel(app.Radialparameterinformation_2);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.Radialparameterinformation_2, 'numeric');
            app.UEditFieldLabel = uilabel(app.Radialparameterinformation_2);
            app.UEditFieldLabel.Text = 'U';
            app.UEditField = uieditfield(app.Radialparameterinformation_2, 'numeric');
            app.Mainparameterinformation_2 = uipanel(app.ParametricMethodTab);
            app.Mainparameterinformation_2.Title = 'Main parameter information';
            app.OverallwheelbaseLLabel_2 = uilabel(app.Mainparameterinformation_2);
            app.OverallwheelbaseLLabel_2.Text = 'Overall wheelbase (L):';
            app.OverallwheelbaseL_2 = uieditfield(app.Mainparameterinformation_2, 'numeric');
            app.DiameterDLabel_2 = uilabel(app.Mainparameterinformation_2);
            app.DiameterDLabel_2.Text = 'Diameter (D):';
            app.DiameterD_2 = uieditfield(app.Mainparameterinformation_2, 'numeric');
            app.HubdiameterratioRhRLabel_2 = uilabel(app.Mainparameterinformation_2);
            app.HubdiameterratioRhRLabel_2.Text = 'Hub diameter ratio (Rh/R):';
            app.HubdiameterratioRhRE_2 = uieditfield(app.Mainparameterinformation_2, 'numeric');
            app.NumberofbladeszLabel_2 = uilabel(app.Mainparameterinformation_2);
            app.NumberofbladeszLabel_2.Text = 'Number of blades (z):';
            app.Numberofbladesz_2 = uieditfield(app.Mainparameterinformation_2, 'numeric');
            app.ShowinSection_2 = uibutton(app.Mainparameterinformation_2, 'push');
            app.ShowinSection_2.ButtonPushedFcn = createCallbackFcn(app, @ShowinSection_2ButtonPushed, true);
            app.ShowinSection_2.Text = 'Show Section';
            app.ShowinSurface_2 = uibutton(app.Mainparameterinformation_2, 'push');
            app.ShowinSurface_2.ButtonPushedFcn = createCallbackFcn(app, @ShowinSurface_2ButtonPushed, true);
            app.ShowinSurface_2.Text = 'Show Surface';
            app.ShowinPoints_2 = uibutton(app.Mainparameterinformation_2, 'push');
            app.ShowinPoints_2.ButtonPushedFcn = createCallbackFcn(app, @ShowinPoints_2ButtonPushed, true);
            app.ShowinPoints_2.Text = 'Show Points';
            app.Export_2 = uibutton(app.Mainparameterinformation_2, 'push');
            app.Export_2.ButtonPushedFcn = createCallbackFcn(app, @Export_2ButtonPushed, true);
            app.Export_2.Text = 'Exprot';
            % Show the figure after all components are created
            app.PlatToroidalPropv10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlatToroidalProp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlatToroidalPropv10UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlatToroidalPropv10UIFigure)
        end
    end
end